classdef FinalProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CrystalStructureTab             matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        Step1UnitCellGeometryPanel      matlab.ui.container.Panel
        AxialLengthsnmLabel             matlab.ui.control.Label
        AnglesLabel                     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        angle1                          matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        angle2                          matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        angle3                          matlab.ui.control.NumericEditField
        CellPlot                        matlab.ui.control.Button
        SystemLabel                     matlab.ui.control.Label
        SystemType                      matlab.ui.control.EditField
        aEditFieldLabel                 matlab.ui.control.Label
        length1                         matlab.ui.control.NumericEditField
        cEditFieldLabel                 matlab.ui.control.Label
        length3                         matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        length2                         matlab.ui.control.NumericEditField
        Step2BravaisLatticePanel        matlab.ui.container.Panel
        BravaisLatticeDropDownLabel     matlab.ui.control.Label
        BravaisLattice                  matlab.ui.control.DropDown
        AtomicRadiusnmEditFieldLabel    matlab.ui.control.Label
        AtomicRadius                    matlab.ui.control.NumericEditField
        PlotAtom                        matlab.ui.control.Button
        Step3InterstitialsPanel         matlab.ui.container.Panel
        InterstitialRadiusnmEditFieldLabel  matlab.ui.control.Label
        IRtet                           matlab.ui.control.NumericEditField
        Plottet                         matlab.ui.control.Button
        OctahedralSiteLabel             matlab.ui.control.Label
        InterstitialRadiusnmEditField_2Label  matlab.ui.control.Label
        IRoct                           matlab.ui.control.NumericEditField
        Plotoct                         matlab.ui.control.Button
        TetrahedralSiteLabel            matlab.ui.control.Label
        ClearPlotButton                 matlab.ui.control.Button
        holdon                          matlab.ui.control.CheckBox
        ViewpointLabel                  matlab.ui.control.Label
        AxisView                        matlab.ui.control.DropDown
        PlanesandDirectionsPanel        matlab.ui.container.Panel
        hLabel                          matlab.ui.control.Label
        h1                              matlab.ui.control.NumericEditField
        lLabel                          matlab.ui.control.Label
        l1                              matlab.ui.control.NumericEditField
        kLabel                          matlab.ui.control.Label
        k1                              matlab.ui.control.NumericEditField
        family1                         matlab.ui.control.CheckBox
        plot1                           matlab.ui.control.Button
        h2                              matlab.ui.control.NumericEditField
        l2                              matlab.ui.control.NumericEditField
        k2                              matlab.ui.control.NumericEditField
        PorD1                           matlab.ui.control.DropDown
        plot2                           matlab.ui.control.Button
        family2                         matlab.ui.control.CheckBox
        PorD2                           matlab.ui.control.DropDown
        AnglebetweenspecifiedplanesdirectionsEditFieldLabel  matlab.ui.control.Label
        pdangle                         matlab.ui.control.EditField
        d2                              matlab.ui.control.EditField
        dspacingnmEditFieldLabel        matlab.ui.control.Label
        d1                              matlab.ui.control.EditField
        ReciprocalLatticeTab            matlab.ui.container.Tab
        UIAxes_4                        matlab.ui.control.UIAxes
        ClearPlotsButton_2              matlab.ui.control.Button
        holdon_2                        matlab.ui.control.CheckBox
        AxisView_2                      matlab.ui.control.DropDown
        PlanesPanel                     matlab.ui.container.Panel
        plot1_2                         matlab.ui.control.Button
        plot1_3                         matlab.ui.control.Button
        h5                              matlab.ui.control.NumericEditField
        l5                              matlab.ui.control.NumericEditField
        k5                              matlab.ui.control.NumericEditField
        d5                              matlab.ui.control.EditField
        plot1_4                         matlab.ui.control.Button
        h6                              matlab.ui.control.NumericEditField
        l6                              matlab.ui.control.NumericEditField
        k6                              matlab.ui.control.NumericEditField
        d6                              matlab.ui.control.EditField
        plot1_5                         matlab.ui.control.Button
        h7                              matlab.ui.control.NumericEditField
        l7                              matlab.ui.control.NumericEditField
        k7                              matlab.ui.control.NumericEditField
        d7                              matlab.ui.control.EditField
        Plotupto4planesatatimeLabel     matlab.ui.control.Label
        dstar5                          matlab.ui.control.EditField
        dstar6                          matlab.ui.control.EditField
        dstar7                          matlab.ui.control.EditField
        dspacingnmEditFieldLabel_2      matlab.ui.control.Label
        d4                              matlab.ui.control.EditField
        dLabel                          matlab.ui.control.Label
        dstar4                          matlab.ui.control.EditField
        WavelengthnmEditFieldLabel      matlab.ui.control.Label
        wavelengthRL                    matlab.ui.control.NumericEditField
        hLabel_3                        matlab.ui.control.Label
        h4                              matlab.ui.control.NumericEditField
        lLabel_3                        matlab.ui.control.Label
        l4                              matlab.ui.control.NumericEditField
        kLabel_3                        matlab.ui.control.Label
        k4                              matlab.ui.control.NumericEditField
        ViewpointLabel_2                matlab.ui.control.Label
        UIAxes_5                        matlab.ui.control.UIAxes
        UnitCellGeometryPanel           matlab.ui.container.Panel
        AxialLengthsnmLabel_2           matlab.ui.control.Label
        AnglesLabel_2                   matlab.ui.control.Label
        CellPlot_2                      matlab.ui.control.Button
        aEditFieldLabel_2               matlab.ui.control.Label
        length1_2                       matlab.ui.control.NumericEditField
        cEditFieldLabel_2               matlab.ui.control.Label
        length3_2                       matlab.ui.control.NumericEditField
        bEditFieldLabel_2               matlab.ui.control.Label
        length2_2                       matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        angle1_2                        matlab.ui.control.NumericEditField
        EditField_2Label_2              matlab.ui.control.Label
        angle2_2                        matlab.ui.control.NumericEditField
        Label_8                         matlab.ui.control.Label
        angle3_2                        matlab.ui.control.NumericEditField
        ViewingDirectionDropDownLabel   matlab.ui.control.Label
        ViewingDirectionDropDown        matlab.ui.control.DropDown
        XRayCrystallographyTab          matlab.ui.container.Tab
        XRayCrystallographyBraggsLawPanel  matlab.ui.container.Panel
        SpecifyBravaisLatticeDropDownLabel  matlab.ui.control.Label
        Blattice                        matlab.ui.control.DropDown
        SelectparametertocalculateButtonGroup  matlab.ui.container.ButtonGroup
        xraywavelengthButton            matlab.ui.control.RadioButton
        nOrderofreflectionButton        matlab.ui.control.RadioButton
        BraggangleButton                matlab.ui.control.RadioButton
        dPlanespacingButton             matlab.ui.control.RadioButton
        dnmEditFieldLabel               matlab.ui.control.Label
        d3                              matlab.ui.control.EditField
        nmEditFieldLabel                matlab.ui.control.Label
        lambda_bragg                    matlab.ui.control.EditField
        nEditFieldLabel                 matlab.ui.control.Label
        nEditField                      matlab.ui.control.EditField
        Label_6                         matlab.ui.control.Label
        theta_bragg                     matlab.ui.control.EditField
        hEditFieldLabel                 matlab.ui.control.Label
        h3                              matlab.ui.control.EditField
        kEditFieldLabel                 matlab.ui.control.Label
        k3                              matlab.ui.control.EditField
        lEditFieldLabel                 matlab.ui.control.Label
        l3                              matlab.ui.control.EditField
        EnterallhighlightedvaluesLabel  matlab.ui.control.Label
        AllowedreflectionLabel          matlab.ui.control.Label
        anmLabel                        matlab.ui.control.Label
        a_bragg                         matlab.ui.control.EditField
        cnmLabel                        matlab.ui.control.Label
        c_bragg                         matlab.ui.control.EditField
        PoleFiguresTab                  matlab.ui.container.Tab
        PlotPoles                       matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        ClearPlotsButton                matlab.ui.control.Button
        holdon_pole                     matlab.ui.control.CheckBox
        UIAxes_3                        matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        FamilyofdirectionsLabel         matlab.ui.control.Label
        hLabel_2                        matlab.ui.control.Label
        h_pole                          matlab.ui.control.NumericEditField
        lLabel_2                        matlab.ui.control.Label
        l_pole                          matlab.ui.control.NumericEditField
        kLabel_2                        matlab.ui.control.Label
        k_pole                          matlab.ui.control.NumericEditField
        Panel_2                         matlab.ui.container.Panel
        EulerAnglesLabel                matlab.ui.control.Label
        EulerFile                       matlab.ui.control.EditField
        usefile                         matlab.ui.control.CheckBox
        Label_3                         matlab.ui.control.Label
        psi                             matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        phi                             matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        theta                           matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        points % points refer to the points of the unit cell
        hkl % Description
        PlotPorD % flag to plot plan or direction #1 or #2
        ReciprocalLatticeFlag = 0; % Description
        iPlane = 4; % Description
    end
    
    methods (Access = public)
        
        function PlotDirection(app,h, k, l, a, b, c, point)
            % define the two points that will make up the vector
            % specify origin such that the vector is inside the unit cell
            
            if  ~strcmpi(app.SystemType.Value,'Hexagonal')
                
                beta = app.angle2.Value;
                
                % determine origin based on sign of x,  y, and z
                if h < 0
                    potentialPts(1,:) = [3, 4, 5, 8]; % if x is negative, these are the potential points
                else
                    potentialPts(1,:) = [1, 2, 6, 7]; % if x is positive, these are the potential points
                end
                if k < 0
                    potentialPts(2,:) = [2, 3, 7, 8];
                else
                    potentialPts(2,:) = [1, 4, 5, 6];
                end
                if l < 0
                    potentialPts(3,:) = [5, 6, 7, 8];
                else
                    potentialPts(3,:) = [1, 2, 3, 4];
                end
                % determine the common point between x, y, and z
                Origin = intersect(intersect(potentialPts(1,:),potentialPts(2,:)),potentialPts(3,:));
                
                P1 = point{Origin};
                divisor = max([abs(h),abs(k),abs(l)]);
                
                P2 = [P1(1)+b*(h/divisor); P1(2)+a*(k/divisor); P1(3)+c*(l/divisor)];
                Q = [1 0 0; 0 1 0; 0 cosd(beta) sind(beta)];
                P2 = transpose(Q)*[1 0 0; 0 1 0; 0 0 1]*P2;
                
            else
                % calculate uvtw
                u = 1/3*(2*h-k);
                v = 1/3*(2*k-h);
                t = -(u+v);
                w = l;
                
                % multiply by 3 to get rid of denominator
                u = u*3; v = v*3; t = t*3; w = w*3;
                
                Origin(1,1) =point{6}(1,1); % x-value is the same as point 6
                Origin(1,2) = mean([point{1}(1,2) point{2}(1,2)]); % y-value is the average of point 1 and point 2 y-value
                
                if l >= 0 % If l is positive origin should be at z = 0
                    Origin(1,3) = 0;
                else % If l is negative origin should be at z = c
                    Origin(1,3) = c;
                end
                
                P1 = Origin; % origin
                divisor = max([abs(u),abs(v),abs(t),abs(w)]);
                u = u/divisor; v = v/divisor; t = t/divisor; w = w/divisor;
                
                % resolve a1, a2, and a3 into x-y components
                a1x = 0; % no x-component for a1
                a1y = v; % only y-component for a1
                
                a2x = u*cosd(30);
                a2y = -u*sind(30);
                
                a3x = -t*cosd(30);
                a3y = -t*sind(30);
                
                P2 = [P1(1)+a*(a1x+a2x+a3x), P1(2)+a*(a1y+a2y+a3y), P1(3)+w*c];
                
            end
            
            plot3(app.UIAxes,[P1(1);P2(1)],[P1(2);P2(2)],[P1(3);P2(3)],'g');  % Draw a line between the points
            p = P2-P1;
            arrow = 0.1;  % Size of arrow head relative to the length of the vector
            width = 0.2;  % Width of the base of the arrow head relative to the length
            P3(1,:) = [P2(1)-arrow*(p(1)+width*(p(2))); P2(1); P2(1)-arrow*(p(1)-width*(p(2)))];
            try
                P3(2,:) = [P2(2)-arrow*(p(2,2)-width*(p(1))); P2(2); P2(2)-arrow*(p(2,2)+width*(p(1)))];
                P3(3,:)=[P2(3)-arrow*p(3,3); P2(3); P2(3)-arrow*p(3,3)];
            catch
                P3(2,:) = [P2(2)-arrow*(p(1,2)-width*(p(1))); P2(2); P2(2)-arrow*(p(1,2)+width*(p(1)))];
                P3(3,:)=[P2(3)-arrow*p(1,3); P2(3); P2(3)-arrow*p(1,3)];
            end
            
            plot3(app.UIAxes,P3(1,:),P3(2,:),P3(3,:),'g')  % Plot arrow head
            
        end
        
        
        function P1 = PlotPlane(app,h, k, l, a, b, c, point)
            if  ~strcmpi(app.SystemType.Value,'Hexagonal') || app.ReciprocalLatticeFlag == 1
                if app.ReciprocalLatticeFlag == 1
                    UIFigureID = 'app.UIAxes_4';
                    beta = app.angle2_2.Value;
                else
                    UIFigureID = 'app.UIAxes';
                    beta = app.angle2.Value;
                end
                Q = [1 0 0; 0 1 0; 0 cosd(beta) sind(beta)];
                
                % determine origin based on sign of x,  y, and z
                if h < 0
                    potentialPts(1,:) = [3, 4, 5, 8]; % if x is negative, these are the potential points
                else
                    potentialPts(1,:) = [1, 2, 6, 7]; % if x is positive, these are the potential points
                end
                if k < 0
                    potentialPts(2,:) = [2, 3, 7, 8];
                else
                    potentialPts(2,:) = [1, 4, 5, 6];
                end
                if l < 0
                    potentialPts(3,:) = [5, 6, 7, 8];
                else
                    potentialPts(3,:) = [1, 2, 3, 4];
                end
                % determine the common point between x, y, and z
                iOrigin = intersect(intersect(potentialPts(1,:),potentialPts(2,:)),potentialPts(3,:));
                Origin = point{iOrigin}';
                % take inverse
                x = Origin(1)+b*1/h;
                y = Origin(2)+a*1/k;
                z = Origin(3)+c*1/l;
                
                if ~isempty(find([h k l]==0)) % if any of h, k, or l are zero there will be 4 points
                    if h == 0
                        x = b;
                    end
                    if k == 0
                        y = a;
                    end
                    if l == 0
                        z = c;
                    end
                    
                    if k == 0 % parallel to y-axis
                        y = a;
                        if h == 0 % 0 0 1
                            P1 = [x x Origin(1) Origin(x); Origin(2) y y Origin(2); z z z z];
                        elseif l == 0 % 1 0 0
                            P1 = [x x x x; Origin(2) y y Origin(2); Origin(3) Origin(3) z z];
                        else % 1 0 1
                            P1 = [x x Origin(1) Origin(1); Origin(2) y y Origin(2); Origin(3) Origin(3) z z];
                        end
                    end
                    if h == 0 && k ~=0 && l ~= 0 % 0 1 1
                        P1 = [x Origin(1) Origin(1) x; y y  Origin(2) Origin(2); Origin(3) Origin(3) z z];
                    end
                    
                    if h ~= 0 && k ~=0 && l == 0 % 1 1 0
                        P1 = [Origin(1) x x Origin(1); Origin(2) y  y Origin(2);  Origin(3) Origin(3) z z];
                    end
                    
                    if h == 0 && l == 0 % 0 1 0
                        P1 = [x Origin(1) Origin(1) x; y y y y; Origin(3) Origin(3) z z];
                    end
                else % if all ~= 0
                    P1 = [x Origin(1) Origin(1); Origin(2) y Origin(2); Origin(3) Origin(3) z];
                end
                
                for i = 1:length(P1)
                    P1(:,i) = transpose(Q)*[1 0 0; 0 1 0; 0 0 1]*P1(:,i);
                end
                eval(['fill3(',UIFigureID,',P1(1,:), P1(2,:), P1(3,:), [1 0.4 0.6]);']);
                eval(['alpha(',UIFigureID,', ".3");'])
            else    % For hexagonal structures
                i = -(h+k); % calculate i in (hkiil)
                % take inverse
                h = 1/h; k = 1/k; i = 1/i; l = 1/l;
                
                Origin(1,1) =point{6}(1,1); % x-value is the same as point 6
                Origin(1,2) = mean([point{1}(1,2) point{2}(1,2)]); % y-value is the average of point 1 and point 2 y-value
                
                if l >= 0 % If l is positive origin should be at z = 0
                    Origin(1,3) = 0;
                    opposite = c;
                else % If l is negative origin should be at z = c
                    Origin(1,3) = c;
                    opposite = 0;
                end
                
                % resolve a1, a2, and a3 into x-y components
                a1x = Origin(1)+a*0; % no x-component for a1
                a1y = Origin(2)+a*k; % only y-component for a1
                
                a2x = Origin(1)+a*h*cosd(30);
                a2y = Origin(2)+a*(-h*sind(30));
                
                a3x = Origin(1)+a*(-i*cosd(30));
                a3y = Origin(2)+a*(-i*sind(30));
                
                %                 P2 = [P1(1)+a*(a1x+a2x+a3x), P1(2)+a*(a1y+a2y+a3y), P1(3)+w*c];
                
                P1 = [ a2x a2y Origin(3); a3x a3y Origin(3);  a1x, a1y, Origin(3); a1x, a1y, opposite;  a3x a3y opposite; a2x a2y opposite];
                
                % select 3 points that are not Inf
                count = 1;
                for iP = 1:size(P1,1)
                    if isempty(find(abs(P1(iP,:))==Inf))
                        Points(count,:) = P1(iP,:); % take entire row
                        count = count+1;
                    end
                end
                
                if abs(h)==Inf && abs(k) == Inf && abs(i) == Inf
                    Points(1,:) = [0 a Origin(3)];
                    Points(2,:) = [0 0 Origin(3)];
                    Points(4,:) = [a a Origin(3)];
                end
                
                p1=Points(1,:);
                p2=Points(2,:);
                p3=Points(4,:);
                
                if abs(l)~=Inf && ~(abs(h)==Inf && abs(k) == Inf && abs(i) == Inf) % when l is not zero the third point will be at (0,0,l)
                    p3=[Origin(1) Origin(2) l*c];
                end
                
                %                                 fill3(app.UIAxes,[p1(1), p2(1), p3(1)],[p1(2), p2(2), p3(2)],[p1(3), p2(3), p3(3)],'g')
                
                normal = cross(p1 - p2, p1 - p3);
                x1 = p1(1); y1 = p1(2); z1 = p1(3);
                w = null(normal); % Find two orthonormal vectors which are orthogonal to v
                
                %                 [P,Q] = meshgrid(-c:c/2); % Provide grid
                %                 [P,Q] = meshgrid(-0.5*a:2.5*a:2*a,-3*a:3.5*a:0.5*a); % Provide grid
                [P,Q] = meshgrid(-2.5*a:4.5*a:2*a,-3*a:6*a:3*a); % Provide grid
                X = x1+w(1,1)*P+w(1,2)*Q;
                Y = y1+w(2,1)*P+w(2,2)*Q;
                Z = z1+w(3,1)*P+w(3,2)*Q;
                
                surf(app.UIAxes,X,Y,Z)
                
                alpha(app.UIAxes, '.3')
                
            end
            
            app.ReciprocalLatticeFlag = 0;
        end
        
        
        function PoleFigures(Poles, Euler,Name,app)
            %% Step 1/Step 2
            
            % generate the rotation matrix g
            % g is a forwards transformation which moves from the global coordinate
            % system to the crystal coordinate system
            
            % generate g's transpose, gT
            % gT is a backwards transformation moving from crystal coordinate system to
            % global coordinate system
            
            psi   = Euler(:,1);
            theta = Euler(:,2);
            phi   = Euler(:,3);
            
            for i = 1:size(Euler,1)
                g{i} = [-sind(psi(i,1))*sind(phi(i,1))-cosd(psi(i,1))*cosd(phi(i,1))*cosd(theta(i,1)), sind(psi(i,1))*cosd(phi(i,1))-cosd(psi(i,1))*sind(phi(i,1))*cosd(theta(i,1)), cosd(psi(i,1))*sind(theta(i,1));...
                    cosd(psi(i,1))*sind(phi(i,1))-sind(psi(i,1))*cosd(phi(i,1))*cosd(theta(i,1)), -cosd(psi(i,1))*cosd(phi(i,1))-sind(psi(i,1))*sind(phi(i,1))*cosd(theta(i,1)), sind(psi(i,1))*sind(theta(i,1));...
                    cosd(phi(i,1))*sind(theta(i,1)), sind(phi(i,1))*sind(theta(i,1)), cosd(theta(i,1))];
                gT{i} = g{i}'; % transpose
            end
            
            %% Step 3
            
            % map the pole vectors using x = gTPoles
            for i = 1:size(Euler,1)
                for j = 1:size(Poles,1)
                    cartesian{i,j} =  gT{i}*Poles(j,:)'; % 1,000 x 8
                end
            end
            
            clear phi theta psi % theta and phi will be redefined for defining spherical coordinates
            
            %% Step 4
            
            % select the 111 poles in the northern hemisphere.
            % search for those with a z-coordinate greater than 0, with a tolerance of
            % -1e-5 to capture those on the equitorial plane
            for i = 1:size(cartesian,1)
                count = 1;
                for j = 1:size(cartesian,2)
                    if cartesian{i,j}(3,1)>=0%> -1e-5 % find z-values that are >0 (tolerance of -1e-5)
                        cartesian_north{i,count} = cartesian{i,j};
                        count = count+1;
                    end
                end
            end
            
            %% Step 5
            
            % convert each pole from step 4 from cartesian to spherical coordinates
            for i = 1:size(cartesian_north,1)
                for j = 1:size(cartesian_north,2)
                    x = cartesian_north{i,j}(1,1);
                    y = cartesian_north{i,j}(2,1);
                    z = cartesian_north{i,j}(3,1);
                    hypotxy = hypot(x,y);
                    rho = hypot(hypotxy,z);
                    theta = acos(z/rho);
                    phi = atan2(y,x);
                    spherical_north{i,j} = [rho; theta; phi];
                end
            end
            
            %% Step 6a
            
            % Project the spherical coordinates onto a circle
            P_equalarea = sqrt(2);
            
            for i = 1:size(spherical_north,1)
                for j = 1:size(spherical_north,2)
                    theta = spherical_north{i,j}(2,1);
                    phi = spherical_north{i,j}(3,1);
                    
                    P_stereographic(i,j) = 1/cos(theta/2); % 1/cos(theta/2)
                    
                    x_stereographic(i,j) = P_stereographic(i,j)*sin(theta/2)*cos(phi);
                    y_stereographic(i,j) = P_stereographic(i,j)*sin(theta/2)*sin(phi);
                    
                    x_equalarea(i,j) = P_equalarea*sin(theta/2)*cos(phi);
                    y_equalarea(i,j) = P_equalarea*sin(theta/2)*sin(phi);
                    
                end
            end
            
            %% Step 6b
            
            % plot
            
            plot(app.UIAxes_2,x_stereographic, y_stereographic,'LineStyle','none','Marker','o','MarkerEdgeColor','none','MarkerFaceColor','r','MarkerSize',3)
            circle = 0:pi/30:2*pi; hold(app.UIAxes_2,'on');
            plot(app.UIAxes_2,cos(circle),sin(circle),'k');
            axis(app.UIAxes_2, 'equal');
            title(app.UIAxes_2,['Stereographic Projection of ',Name])
            xlabel(app.UIAxes_2, 'x')
            ylabel(app.UIAxes_2, 'y')
            hold(app.UIAxes_2, 'off');
            
            plot(app.UIAxes_3,x_equalarea,y_equalarea,'LineStyle','none','Marker','o','MarkerEdgeColor','none','MarkerFaceColor','g','MarkerSize',3)
            hold(app.UIAxes_3,'on');
            plot(app.UIAxes_3,cos(circle),sin(circle),'k');
            axis(app.UIAxes_3, 'equal');
            hold(app.UIAxes_3, 'off');
            title(app.UIAxes_3,['Equal Area Projection of ',Name])
            xlabel(app.UIAxes_3, 'x')
            ylabel(app.UIAxes_3, 'y')
            
        end
        
        
        function CalculateBragg(app)
            % check to see if all necessary inputs are filled out. If not, set variable equal to ''
            lambda= str2double(app.lambda_bragg.Value);
            n = str2double(app.nEditField.Value);
            theta_b= str2double(app.theta_bragg.Value);
            dhkl = str2double(app.d3.Value);
            selectedButton = app.SelectparametertocalculateButtonGroup.SelectedObject.Text;
            
            if contains(selectedButton,'wavelength')
                variable = 'lambda_bragg';
            elseif contains(selectedButton,'reflection')
                variable = 'nEditField';
            elseif contains(selectedButton,'angle')
                variable = 'theta_bragg';
            elseif contains(selectedButton,'spacing')
                variable = 'd3';
            end
            
            if contains(selectedButton,'wavelength') && ( ~isnan(n) && ~isnan(theta_b) && ~isnan(dhkl))
                app.lambda_bragg.Value = num2str(dhkl*2*sind(theta_b)/n,'%1.5f');
            elseif contains(selectedButton,'reflection') && (~isnan(lambda) && ~isnan(theta_b) && ~isnan(dhkl))
                app.nEditField.Value = num2str(dhkl*2*sind(theta_b)/lambda,'%1.5f');
            elseif contains(selectedButton,'angle') && (~isnan(lambda) && ~isnan(n) && ~isnan(dhkl))
                app.theta_bragg.Value = num2str(asind(n*lambda/(2*dhkl)),'%1.5f');
            elseif contains(selectedButton,'spacing') && (~isnan(lambda) && ~isnan(n) && ~isnan(theta_b))
                app.d3.Value = num2str(n*lambda/(2*sind(theta_b)),'%1.5f');
            else
                app.(variable).Value = '';
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.hkl = 1;
        end

        % Button pushed function: CellPlot
        function CellPlotPushed(app, event)
            
            if app.ReciprocalLatticeFlag == 0
                app.AxisView.Value = '3-D view';
                view(app.UIAxes,3); % 3-D view
                if app.holdon.Value == 0
                    cla(app.UIAxes)
                end
                % create 3-D plot of crystal structure
                a = app.length1.Value;
                b = app.length2.Value;
                c = app.length3.Value;
                alpha = app.angle1.Value;
                beta = app.angle2.Value;
                gamma = app.angle3.Value;
                UIFigureID = 'app.UIAxes';
            else
                if app.holdon_2.Value == 0
                    cla(app.UIAxes_4)
                end
                % create 3-D plot of crystal structure
                a = app.length1_2.Value;
                b = app.length2_2.Value;
                c = app.length3_2.Value;
                alpha = app.angle1_2.Value;
                beta = app.angle2_2.Value;
                gamma = app.angle3_2.Value;
                UIFigureID = 'app.UIAxes_4';
            end
            
            
            if strcmpi(app.SystemType.Value,'Hexagonal') && app.ReciprocalLatticeFlag~=1
                pdangle = 30;
                point{1} = [0, 0, 0];
                point{2} = [0 a 0];
                point{3} = [a*cosd(pdangle), a+a*sind(pdangle), 0];
                point{4} = [2*a*cosd(pdangle), a, 0];
                point{5} = [2*a*cosd(pdangle), 0, 0];
                point{6} = [a*cosd(pdangle), -a*sind(pdangle), 0];
                
                for i = 1:6
                    if i == 6
                        point{1+i} = point{i};
                        point{1+i}(1,3) = c; % points 6-12 have a z-value of c
                    else
                        point{7+i} = point{i};
                        point{7+i}(1,3) = c; % points 6-12 have a z-value of c
                    end
                end
                
                % draw lines between adjacent points
                for i = 1:11 % plot first 11 edges of structure
                    if i>1
                        hold(app.UIAxes,'on'); % hold axes on after first 3-D line has been drawn
                    end
                    X = [point{i}(1,1), point{i+1}(1,1)];
                    Y = [point{i}(1,2), point{i+1}(1,2)];
                    Z = [point{i}(1,3), point{i+1}(1,3)];
                    eval(['plot3(',UIFigureID,',X, Y, Z,"b");'])
                end
                
                for i = 1:5 % plot next 5 edges of structure
                    X = [point{i}(1,1), point{i+7}(1,1)];
                    Y = [point{i}(1,2), point{i+7}(1,2)];
                    Z = [point{i}(1,3), point{i+7}(1,3)];
                    eval(['plot3(',UIFigureID,',X, Y, Z,"b");'])
                end
                
                for i = 1:2 % plot next 2 edges of structure
                    if i == 2
                        i = 7;
                    end
                    X = [point{i}(1,1), point{i+5}(1,1)];
                    Y = [point{i}(1,2), point{i+5}(1,2)];
                    Z = [point{i}(1,3), point{i+5}(1,3)];
                    eval(['plot3(',UIFigureID,',X, Y, Z,"b");'])
                end
                
            else
                
                point{1} = [0, 0, 0];
                point{2} = [0, a, 0];
                point{3} = [b, a, 0];
                point{4} = [b, 0, 0];
                point{5} = [b, 0, c];
                point{6} = [0, 0, c];
                point{7} = [0, a, c];
                point{8} = [b, a, c];
                
                % apply angles
                
                % point 1 (origin) never changes
                
                % Beta adjusts points 5-8 horizontally in the Y (a) direction and vertically in the Z (c) direction
                % Alpha adjusts points 5-8 horizontally in the X (b) direction and vertically in the Z (c) direction
                Xshift = c*cosd(alpha); % if alpha > 0 Xshift will be  negative
                Yshift = c*cosd(beta); % if beta > 0 Yshift will be negative
                Zvalue = c*sind(beta)*sind(alpha);
                for i = 5:8
                    point{i}(1,1) = point{i}(1,1)+Xshift; % New X-value (b)
                    point{i}(1,2) = point{i}(1,2)+Yshift; % New Y-value (a)
                    point{i}(1,3) = Zvalue; % all points have the same Z-value (c)
                end
                
                % Gamma adjusts points 3, 4, 5, and 8 horizontally in the Y (a) direction and vertically in the X (b) direction
                Yshift = b*cosd(gamma);
                Xvalue = b*sind(gamma);
                
                for i = 3:4
                    point{i}(1,1) = Xvalue;
                    point{i}(1,2) = point{i}(1,2)+Yshift;
                end
                
                ipoint = [5, 8];
                for i = 1:2
                    j = ipoint(i);
                    point{j}(1,1) = point{j}(1,1)*sind(gamma); % modified b times sin(gamma)
                    point{j}(1,2) = point{j}(1,2)+Yshift;
                end
                
                % draw lines between adjacent points
                for i = 1:7 % plot first 7 edges of structure
                    if i>1
                        eval(['hold(',UIFigureID,',"on");']); % hold axes on after first 3-D line has been drawn
                    end
                    X = [point{i}(1,1), point{i+1}(1,1)];
                    Y = [point{i}(1,2), point{i+1}(1,2)];
                    Z = [point{i}(1,3), point{i+1}(1,3)];
                    eval(['plot3(',UIFigureID,',X, Y, Z,"b");'])
                end
                
                for i = 1:3 % plot next 3 edges of structure
                    X = [point{i}(1,1), point{i+5}(1,1)];
                    Y = [point{i}(1,2), point{i+5}(1,2)];
                    Z = [point{i}(1,3), point{i+5}(1,3)];
                    eval(['plot3(',UIFigureID,',X, Y, Z,"b");'])
                end
                
                for i = 1:2 % plot next 2 edges of structure
                    if i == 2
                        i = 5;
                    end
                    X = [point{i}(1,1), point{i+3}(1,1)];
                    Y = [point{i}(1,2), point{i+3}(1,2)];
                    Z = [point{i}(1,3), point{i+3}(1,3)];
                    eval(['plot3(',UIFigureID,',X, Y, Z,"b");'])
                end
                
            end
            
            eval(['axis(',UIFigureID,',"equal");']);
            eval(['zlabel(',UIFigureID,',"Z");']);
            eval(['xlabel(',UIFigureID,',"X");']);
            eval(['ylabel(',UIFigureID,',"Y");']);
            
            app.points = point;
            
            ReciprocalLatticeFlag = 0;
            
        end

        % Value changed function: length1
        function length1ValueChanged(app, event)
            
            value = [app.length1.Value, app.length2.Value, app.length3.Value;...
                app.angle1.Value, app.angle2.Value, app.angle3.Value];
            if value > 0 % if a value greater than zero is specified for all lengths and angles then enable the plot button
                app.CellPlot.Enable = 'on';
                if app.length1.Value == app.length2.Value && app.length2.Value == app.length3.Value % if a = b = c then either cubic or rhombohedral
                    if app.angle1.Value == app.angle2.Value && app.angle2.Value == app.angle3.Value && app.angle1.Value == 90 % cubic
                        app.SystemType.Value = 'Cubic';
                        app.BravaisLattice.Items = {'Simple','Body-centered','Face-centered'};
                    elseif app.angle1.Value == app.angle2.Value && app.angle2.Value == app.angle3.Value && app.angle1.Value~=90 % rhombohedral
                        app.SystemType.Value = 'Rhombohedral';
                        app.BravaisLattice.Items = {'Simple'};
                    else
                        app.SystemType.Value = 'Invalid inputs'; % Angles must all be equal when axial lengths are equal.
                        app.CellPlot.Enable = 'off';
                    end
                elseif app.length1.Value == app.length2.Value && app.length2.Value ~= app.length3.Value % tetragonal or hexagonal
                    if app.angle1.Value == app.angle2.Value && app.angle2.Value == app.angle3.Value && app.angle1.Value == 90 % tetragonal
                        app.SystemType.Value = 'Tetragonal';
                        app.BravaisLattice.Items = {'Simple','Body-centered'};
                    elseif app.angle1.Value == app.angle2.Value && app.angle1.Value == 90 && app.angle3.Value == 120 % hexagonal
                        app.SystemType.Value = 'Hexagonal';
                        app.BravaisLattice.Items = {'Hexagonal Close-packed'};
                    else
                        app.SystemType.Value = 'Invalid inputs'; % Angles must either all equal 90 or alpha and beta must equal 90 and gamma must equal 120
                        app.CellPlot.Enable = 'off';
                    end
                elseif app.length1.Value ~= app.length2.Value && app.length2.Value ~= app.length3.Value && app.length1.Value ~= app.length3.Value % orthorhombic, monoclinic, or triclinic
                    if app.angle1.Value == app.angle2.Value && app.angle2.Value == app.angle3.Value && app.angle1.Value == 90 % orthorhombic
                        app.SystemType.Value = 'Orthorhombic';
                        app.BravaisLattice.Items = {'Simple','Body-centered','Face-centered','Base-centered'};
                    elseif app.angle1.Value == app.angle3.Value && app.angle1.Value == 90 && app.angle2.Value ~= 90 % monoclinic
                        app.SystemType.Value = 'Monoclinic';
                        app.BravaisLattice.Items = {'Simple','Base-centered'};
                    elseif app.angle1.Value ~= app.angle2.Value && app.angle2.Value ~= app.angle3.Value && app.angle1.Value ~= app.angle3.Value && app.angle1.Value ~= 90 && app.angle2.Value ~= 90 && app.angle3.Value ~= 90 % triclinic
                        app.SystemType.Value = 'Triclinic';
                        app.BravaisLattice.Items = {'Simple'};
                    else
                        app.SystemType.Value = 'Invalid inputs'; % Angles must all equal 90 or only alpha and gamma must equal 90 or none of the angles can equal 90
                        app.CellPlot.Enable = 'off';
                    end
                else
                    app.CellPlot.Enable = 'off';
                    app.SystemType.Value = 'Invalid inputs';
                end
            else
                app.CellPlot.Enable = 'off';
                app.SystemType.Value = 'Invalid inputs';
            end
            
            if strcmp(app.CellPlot.Enable,'on') && app.AtomicRadius.Value > 0
                app.PlotAtom.Enable = 'on';
            else
                app.PlotAtom.Enable = 'off';
            end
            
            % enable or disable certain features depending on system type
            app.hkl = 1;
            h1ValueChanged(app, event); % call h1ValueChanged callback to update the pdangle, and d-spacing values
            app.hkl = 2;
            h1ValueChanged(app, event); % call h1ValueChanged callback to update the pdangle, and d-spacing values
            
            if strcmpi(app.SystemType.Value,'Cubic') || strcmpi(app.SystemType.Value,'Orthorhombic') || strcmpi(app.SystemType.Value,'Tetragonal')
                app.Plottet.Enable = 'on';
                app.Plotoct.Enable = 'on';
                app.IRtet.Enable = 'on';
                app.IRoct.Enable = 'on';
            else
                app.Plottet.Enable = 'off';
                app.Plotoct.Enable = 'off';
                app.IRtet.Enable = 'off';
                app.IRoct.Enable = 'off';
            end
            
            % only plot planes and directions on systems that are not rhombohedral or triclinic
            if strcmpi(app.SystemType.Value,'Rhombohedral') || strcmpi(app.SystemType.Value,'Triclinic') || strcmpi(app.SystemType.Value,'Invalid inputs')
                app.plot1.Enable = 'off';
                app.plot2.Enable = 'off';
            else
                app.plot1.Enable = 'on';
                app.plot2.Enable = 'on';
            end
            
        end

        % Value changed function: length2
        function length2ValueChanged(app, event)
            length1ValueChanged(app, event)
            
        end

        % Value changed function: length3
        function length3ValueChanged(app, event)
            length1ValueChanged(app, event)
            
        end

        % Value changed function: angle1
        function angle1ValueChanged(app, event)
            length1ValueChanged(app, event)
            
        end

        % Value changed function: angle2
        function angle2ValueChanged(app, event)
            length1ValueChanged(app, event)
            
        end

        % Value changed function: angle3
        function angle3ValueChanged(app, event)
            length1ValueChanged(app, event)
            
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            cla(app.UIAxes)
        end

        % Button pushed function: PlotAtom
        function PlotAtomButtonPushed(app, event)
            
            CellPlotPushed(app, event); % plot the unit cell to make sure app.points is what is reflected in GUI and that the structure has been plotted
            point = app.points;
            R = app.AtomicRadius.Value;
            a = app.length1.Value;
            c = app.length3.Value;
            app.AxisView.Value = '3-D view';
            view(app.UIAxes,3); % 3-D view
            
            figure1 = figure;
            % plot a sphere with the centerpoint at each of the points in "app.points"
            [x y z] = sphere;
            for i = 1:length(point)
                xshift = point{i}(1,1); % center of the sphere is point{i}
                yshift = point{i}(1,2); % center of the sphere is point{i}
                zshift = point{i}(1,3); % center of the sphere is point{i}
                
                hSurface = surf(app.UIAxes,R*x+xshift, R*y+yshift, R*z+zshift);
                set(hSurface,'FaceColor',[0 0 1], ...
                    'FaceAlpha',0.3,'FaceLighting','gouraud','EdgeColor','none');
            end
            
            % plot additional spheres depending on the bravais lattice type
            if strcmpi(app.BravaisLattice.Value,'Body-centered')
                % insert atom at the center of the lattice
                % the center of the lattice has an x-value equal to the average of points 1 & 4
                % a y-value equal to the average of points 1 & 2
                % a z-value equal to the average of points 1 & 6
                ipoint = [4 2 6];
                for i = 1:3
                    AdditionalPts{1}(1,i) = mean([point{1}(1,i) point{ipoint(i)}(1,i)]);
                end
            elseif strcmpi(app.BravaisLattice.Value,'Face-centered')
                % insert atom at the center of every face (6 total faces)
                % use 3 points to define every face
                face{1} = [1 4 3 2]; % perpindicular to z-axis - mean between points 1 and 4 gives x-position, mean between points 4 and 3 gives y-position, and mean between points 3 and 2 will give z-position
                face{2} = [5 6 7 8]; % perpindicular to z-axis
                face{3} = [1 4 5 4]; % perpindicular to y-axis
                face{4} = [2 3 8 3]; % perpindicular to y-axis
                face{5} = [6 1 2 7]; % perpindicular to x-axis
                face{6} = [5 4 3 8]; % perpindicular to x-axis
                for i = 1:length(face)
                    for j = 1:3
                        AdditionalPts{i}(1,j) = mean([point{face{i}(j)}(1,j) point{face{i}(j+1)}(1,j)]);
                    end
                end
            elseif strcmpi(app.BravaisLattice.Value,'Base-centered')
                % insert atom at the center of the bottom and top face
                % center of points 1-4 - is the midpoint between points 1 and 3, with a z-value of 0
                AdditionalPts{1}(1,1) = mean([point{1}(1,1) point{3}(1,1)]);
                AdditionalPts{1}(1,2) = mean([point{1}(1,2) point{3}(1,2)]);
                AdditionalPts{1}(1,3) = 0;
                % center of points 5-8 is the midpoint between points 6 and 8, with a z-value of point 6 or 8 (same)
                AdditionalPts{2}(1,1) = mean([point{6}(1,1) point{8}(1,1)]);
                AdditionalPts{2}(1,2) = mean([point{6}(1,2) point{8}(1,2)]);
                AdditionalPts{2}(1,3) = point{6}(1,3);
            elseif strcmpi(app.BravaisLattice.Value,'Hexagonal Close-packed')
                % insert atoms at the center of the top and bottom face
                AdditionalPts{1}(1,1) =point{6}(1,1); % x-value is the same as point 6
                AdditionalPts{1}(1,2) = mean([point{1}(1,2) point{2}(1,2)]); % y-value is the average of point 1 and point 2 y-value
                AdditionalPts{1}(1,3) = 0;
                AdditionalPts{2} = AdditionalPts{1};
                AdditionalPts{2}(1,3) = point{7}(1,3); % second point is the same as the first point, except with a z-value equal to c (or same as point 7)
                % insert 3 atoms in a triangular shape in the center of the lattice
                AdditionalPts{3}(1,1) = point{6}(1,1)+point{6}(1,1)/3; % height is point 6 x-value + point 6 xvalue/3
                AdditionalPts{3}(1,2) = point{1}(1,2);
                AdditionalPts{3}(1,3) = c/2;
                AdditionalPts{4}(1,1) = point{6}(1,1)/3; % x-value is 1/3 of point 6 x-value
                AdditionalPts{4}(1,2) = mean([point{1}(1,2) point{2}(1,2)]); % y-value is the mean of point 1 and point 1 y
                AdditionalPts{4}(1,3) = c/2;
                AdditionalPts{5}(1,1) = AdditionalPts{3}(1,1); %same as additional point 3 x-value
                AdditionalPts{5}(1,2) = point{2}(1,2); %same as point 2 y-value
                AdditionalPts{5}(1,3) = c/2;
            end
            
            if ~strcmpi(app.BravaisLattice.Value,'Simple') % if lattice type is not "Simple", plot the additional points
                for i = 1:length(AdditionalPts)
                    xshift = AdditionalPts{i}(1,1); % center of the sphere is base{i}
                    yshift = AdditionalPts{i}(1,2); % center of the sphere is base{i}
                    zshift = AdditionalPts{i}(1,3); % center of the sphere is base{i}
                    
                    hSurface = surf(app.UIAxes,R*x+xshift, R*y+yshift, R*z+zshift);
                    set(hSurface,'FaceColor',[0 0 1], ...
                        'FaceAlpha',0.3,'FaceLighting','gouraud','EdgeColor','none');
                end
            end
            
            close(figure1)
            
        end

        % Value changed function: AtomicRadius
        function AtomicRadiusValueChanged(app, event)
            value = app.AtomicRadius.Value;
            if value>0 && strcmp(app.CellPlot.Enable,'on') % can only plot the atoms if a valid cell structure is input in the Unit Cell Geometry panel
                app.PlotAtom.Enable = 'on';
            else
                app.PlotAtom.Enable = 'off';
            end
        end

        % Value changed function: BravaisLattice
        function BravaisLatticeValueChanged(app, event)
            value = app.BravaisLattice.Value;
            % calculate the atom diameter based on the geometry and lattice type
            
            
        end

        % Value changed function: AxisView
        function AxisViewValueChanged(app, event)
            value = app.AxisView.Value;
            
            if strcmpi(value,'X-Y plane')
                view(app.UIAxes,2); % X-Y plane
            elseif strcmpi(value,'X-Z plane')
                view(app.UIAxes,[0 0]); % X-Z plane
            elseif strcmpi(value,'Y-Z plane')
                view(app.UIAxes,[90 0]); % Y-Z plane
            else
                view(app.UIAxes,3); % 3-D view
            end
            
        end

        % Value changed function: h1
        function h1ValueChanged(app, event)
            
            h= app.(['h',num2str(app.hkl)]).Value;
            k= app.(['k',num2str(app.hkl)]).Value;
            l= app.(['l',num2str(app.hkl)]).Value;
            
            H1= app.h1.Value;
            K1= app.k1.Value;
            L1= app.l1.Value;
            
            H2= app.h2.Value;
            K2= app.k2.Value;
            L2= app.l2.Value;
            
            a = app.length1.Value;
            b = app.length2.Value;
            c = app.length3.Value;
            alpha = app.angle1.Value;
            beta = app.angle2.Value;
            gamma = app.angle3.Value;
            
            % calculate and display d-spacing value
            if strcmpi(app.(['PorD',num2str(app.hkl)]).Value,'Plane')
                if strcmpi(app.SystemType.Value,'Cubic') % Cubic Plane
                    dhkl = sqrt(a^2/(h^2+k^2+l^2));
                elseif strcmpi(app.SystemType.Value,'Tetragonal') % Tetragonal Plane
                    dhkl = sqrt(1/(((h^2+k^2)/a^2) + l^2/c^2));
                elseif strcmpi(app.SystemType.Value,'Hexagonal')  % Hexagonal Plane
                    dhkl = sqrt(1/((4/3*((h^2+h*k+k^2)/a^2)) + l^2/c^2));
                elseif strcmpi(app.SystemType.Value,'Rhombohedral') % Rhombohedral Plane
                    dhkl = sqrt(1/(((h^2+k^2+l^2)*sind(alpha)^2 + 2*(h*k+k*l+h*l)*(cosd(alpha)^2-cosd(alpha))) / (a^2*(1-3*cosd(alpha)^2+2*cosd(alpha)^3))));
                elseif strcmpi(app.SystemType.Value,'Triclinic')  % Triclinic Plane
                    S11 = b^2*c^2*sind(alpha)^2;
                    S22 = a^2*c^2*sind(beta)^2;
                    S33 = a^2*b^2*sind(gamma)^2;
                    S12 = a*b*c^2*(cosd(alpha)*cosd(beta)-cosd(gamma));
                    S23 = a^2*b*c*(cosd(beta)*cosd(gamma)-cosd(alpha));
                    S13 = a*b^2*c*(cosd(gamma)*cosd(alpha)-cosd(beta));
                    V = a*b*c*sqrt((1-cosd(alpha)^2-cosd(beta)^2-cosd(gamma)^2) + 2*(cosd(alpha)*cosd(beta)*cosd(gamma)));
                    dhkl = sqrt(1/( (S11*h^2 + S22*k^2 + S33*l^2 + 2*S12*h*k + 2*S23*k*l + 2*S13*h*l) / V^2));
                elseif strcmpi(app.SystemType.Value,'Orthorhombic') % Orthorhombic Plane
                    dhkl = sqrt(1/(h^2/a^2+k^2/b^2+l^2/c^2));
                elseif strcmpi(app.SystemType.Value,'Monoclinic') % Monoclinic Plane
                    dhkl = sqrt(1/(1/sind(beta)^2 * (h^2/a^2+k^2*sind(beta)^2/b^2 + l^2/c^2 - 2*h*l*cosd(beta)/(a*c)) ));
                else
                    dhkl = '-';
                end
            else % if direction, set dhlk = '-'
                dhkl = '-';
            end
            
            app.(['d',num2str(app.hkl)]).Value = num2str(dhkl);
            
            % calculate angle between planes
            if ~strcmpi(app.PorD1.Value,app.PorD2.Value) % if one is a plane and one is a direction do not calculate angle. Unless it is cubic
                if strcmpi(app.SystemType.Value,'Cubic') % for cubic determine if plane and direction are parallel or perpindicular
                    if H1 == H2 && K1==K2 && L1==L2 % direction and plane are normal to each other
                        phi = num2str(90);
                    elseif H1*H2 + K1*K2 + L1*L2 == 0 % direction is parallel to plane
                        phi = num2str(0);
                    else
                        phi = '-';
                    end
                else
                    phi = '-';
                end
            else % both are planes or both are directions
                if strcmpi(app.SystemType.Value,'Cubic') % Cubic
                    phi = acosd((H1*H2+K1*K2+L1*L2)/sqrt((H1^2+K1^2+L1^2)*(H2^2+K2^2+L2^2)));
                elseif strcmpi(app.SystemType.Value,'Tetragonal') % Tetragonal
                    phi = acosd(( (H1*H2+K1*K2)/a^2 + L1*L2/c^2 ) / sqrt(( (H1^2+K1^2)/a^2 + L1^2/c^2 ) * ( (H2^2+K2^2)/a^2 +L2^2/c^2 )));
                elseif strcmpi(app.SystemType.Value,'Hexagonal')  % Hexagonal
                    phi = acosd( (H1*H2 + K1*K2 + 0.5*(H1*K2+H2*K1) + 3*a^2/(4*c^2)*(L1*L2)) / sqrt((H1^2+K1^2+H1*K1+(3*a^2/(4*c^2)*L1^2)) * (H2^2+K2^2+H2*K2+3*a^2/(4*c^2)*L2^2) ));
                elseif strcmpi(app.SystemType.Value,'Rhombohedral') % Rhombohedral
                    D1 = sqrt(1/(((H1^2+K1^2+L1^2)*sind(alpha)^2 + 2*(H1*K1+K1*L1+H1*L1)*(cosd(alpha)^2-cosd(alpha))) / (a^2*(1-3*cosd(alpha)^2+2*cosd(alpha)^3))));
                    D2 =  sqrt(1/(((H2^2+K2^2+L2^2)*sind(alpha)^2 + 2*(H2*K2+K2*L2+H2*L2)*(cosd(alpha)^2-cosd(alpha))) / (a^2*(1-3*cosd(alpha)^2+2*cosd(alpha)^3))));
                    V = a^3*sqrt(1-3*cosd(alpha)^2+2*cosd(alpha)^3);
                    phi = acosd( a^4*D1*D2/V^2 * (sind(alpha)^2*(H1*H2+K1*K2+L1*L2) + (cosd(alpha)^2-cosd(alpha))*(K1*L2+K2*L1+L1*H2+L2*H1+H1*K2+H2*K1)) );
                elseif strcmpi(app.SystemType.Value,'Triclinic')  % Triclinic
                    S11 = b^2*c^2*sind(alpha)^2;
                    S22 = a^2*c^2*sind(beta)^2;
                    S33 = a^2*b^2*sind(gamma)^2;
                    S12 = a*b*c^2*(cosd(alpha)*cosd(beta)-cosd(gamma));
                    S23 = a^2*b*c*(cosd(beta)*cosd(gamma)-cosd(alpha));
                    S13 = a*b^2*c*(cosd(gamma)*cosd(alpha)-cosd(beta));
                    V = a*b*c*sqrt((1-cosd(alpha)^2-cosd(beta)^2-cosd(gamma)^2) + 2*(cosd(alpha)*cosd(beta)*cosd(gamma)));
                    D1 = sqrt(1/( (S11*H1^2 + S22*K1^2 + S33*L1^2 + 2*S12*H1*K1 + 2*S23*K1*L1 + 2*S13*H1*L1) / V^2));
                    D2 = sqrt(1/( (S11*H2^2 + S22*K2^2 + S33*L2^2 + 2*S12*H2*K2 + 2*S23*K2*L2 + 2*S13*H2*L2) / V^2));
                    phi = acosd(D1*D2/V^2 * (S11*H1*H2 + S22*K1*K2 + S33*L1*L2 + S23*(K1*L2+K2*L1) + S13*(L1*H2+L2*H1) + S12*(H1*K2+H2*K1) ));
                elseif strcmpi(app.SystemType.Value,'Orthorhombic') % Orthorhombic
                    phi = acosd( (H1*H2/a^2 + K1*K2/b^2 + L1*L2/c^2) / sqrt((H1^2/a^2 + K1^2/b^2 + L1^2/c^2)*(H2^2/a^2 + K2^2/b^2 + L2^2/c^2)) );
                elseif strcmpi(app.SystemType.Value,'Monoclinic') % Monoclinic
                    D1 = sqrt(1/(1/sind(beta)^2 * (H1^2/a^2+K1^2*sind(beta)^2/b^2 + L1^2/c^2 - 2*H1*L1*cosd(beta)/(a*c)) ));
                    D2 = sqrt(1/(1/sind(beta)^2 * (H2^2/a^2+K2^2*sind(beta)^2/b^2 + L2^2/c^2 - 2*H2*L2*cosd(beta)/(a*c)) ));
                    phi = acosd(D1*D2/sind(beta)^2 * (H1*H2/a^2 + K1*K2*sind(beta)^2/b^2 + L1*L2/c^2 - (L1*H2+L2*H1)*cosd(beta)/(a*c)));
                else
                    phi = '-';
                end
            end
            
            app.pdangle.Value = num2str(phi);
            
            app.hkl = 1;
            
        end

        % Value changed function: k1
        function k1ValueChanged(app, event)
            app.hkl = 1;
            h1ValueChanged(app, event)
        end

        % Value changed function: l1
        function l1ValueChanged(app, event)
            app.hkl = 1;
            h1ValueChanged(app, event)
        end

        % Value changed function: h2
        function h2ValueChanged(app, event)
            app.hkl = 2;
            h1ValueChanged(app, event)
        end

        % Value changed function: k2
        function k2ValueChanged(app, event)
            app.hkl = 2;
            h1ValueChanged(app, event)
        end

        % Value changed function: l2
        function l2ValueChanged(app, event)
            app.hkl = 2;
            h1ValueChanged(app, event)
        end

        % Button pushed function: plot1
        function plot1ButtonPushed(app, event)
            
            if  isempty(app.PlotPorD)
                app.PlotPorD = 1;  % this means that plot1Button was pushed (not plot2button)
            elseif app.PlotPorD == 0
                app.PlotPorD = 1;  % this means that plot1Button was pushed (not plot2button)
            end
            
            num = app.PlotPorD;
            CellPlotPushed(app, event); % plot the unit cell to make sure app.points is what is reflected in GUI and that the structure has been plotted
            point = app.points;
            a = app.length1.Value;
            b = app.length2.Value;
            c = app.length3.Value;
            h = app.(['h',num2str(num)]).Value;
            k = app.(['k',num2str(num)]).Value;
            l = app.(['l',num2str(num)]).Value;
            app.AxisView.Value = '3-D view';
            view(app.UIAxes,3); % 3-D view
            
            if app.(['family',num2str(num)]).Value == 1 % if the family checkbox is checked
                permutation{1} = perms([h k l]);
                permutation{2} = perms([-h -k -l]);
                permutation{3} = perms([-h k l]);
                permutation{4} = perms([h -k l]);
                permutation{5} = perms([h k -l]);
                permutation{6} = perms([-h -k l]);
                permutation{7} = perms([-h k -l]);
                permutation{8} = perms([h -k -l]);
                count = 1;
                for i = 1:8
                    [~,idx] = unique(permutation{i},'rows');
                    for j = 1:length(idx)
                        family(count,1:3) = permutation{i}(idx(j),1:3);
                        count = count+1;
                    end
                end
                family = unique(family,'rows');
                
                if strcmpi(app.(['PorD',num2str(num)]).Value,'Direction') % Plot directions
                    for i = 1:size(family,1)
                        PlotDirection(app,family(i,1), family(i,2), family(i,3), a, b, c, point)
                    end
                elseif strcmpi(app.(['PorD',num2str(num)]).Value,'Plane') % Plot Planes
                    for i = 1:size(family,1)
                        PlotPlane(app,family(i,1), family(i,2), family(i,3), a, b, c, point);
                    end
                end
            else
                if strcmpi(app.(['PorD',num2str(num)]).Value,'Direction') % Plot directions
                    PlotDirection(app,h, k, l, a, b, c, point)
                elseif strcmpi(app.(['PorD',num2str(num)]).Value,'Plane') % Plot Planes
                    PlotPlane(app,h, k, l, a, b, c, point);
                end
            end
            
            app.PlotPorD = 0;
            
        end

        % Button pushed function: plot2
        function plot2ButtonPushed(app, event)
            app.PlotPorD = 2;
            plot1ButtonPushed(app, event)
        end

        % Value changed function: PorD1
        function PorD1ValueChanged(app, event)
            app.hkl = 1;
            h1ValueChanged(app, event)
        end

        % Value changed function: PorD2
        function PorD2ValueChanged(app, event)
            app.hkl = 2;
            h1ValueChanged(app, event)
        end

        % Button pushed function: PlotPoles
        function PlotPolesButtonPushed(app, event)
            h = app.h_pole.Value;
            k = app.k_pole.Value;
            l = app.l_pole.Value;
            
            permutation{1} = perms([h k l]);
            permutation{2} = perms([-h -k -l]);
            permutation{3} = perms([-h k l]);
            permutation{4} = perms([h -k l]);
            permutation{5} = perms([h k -l]);
            permutation{6} = perms([-h -k l]);
            permutation{7} = perms([-h k -l]);
            permutation{8} = perms([h -k -l]);
            
            count = 1;
            for i = 1:8
                [~,idx] = unique(permutation{i},'rows');
                for j = 1:length(idx)
                    family(count,1:3) = permutation{i}(idx(j),1:3);
                    count = count+1;
                end
            end
            
            family = unique(family,'rows');
            
            if app.holdon_pole.Value == 0
                cla(app.UIAxes_2)
                cla(app.UIAxes_3)
            end
            
            if app.usefile.Value == 0
                EulerAngles = [app.psi.Value, app.theta.Value, app.phi.Value];
            else
                EulerAngles = load(app.EulerFile.Value);
            end
            
            PoleFigures(family,EulerAngles,['<',num2str(h), num2str(k), num2str(l),'>'],app)
            
        end

        % Button pushed function: ClearPlotsButton
        function ClearPlotsButtonPushed(app, event)
            cla(app.UIAxes_2)
            cla(app.UIAxes_3)
        end

        % Value changed function: usefile
        function usefileValueChanged(app, event)
            value = app.usefile.Value;
            if value == 1
                app.psi.Enable = 'off';
                app.phi.Enable = 'off';
                app.theta.Enable = 'off';
                app.EulerFile.Enable = 'on';
            else
                app.psi.Enable = 'on';
                app.phi.Enable = 'on';
                app.theta.Enable = 'on';
                app.EulerFile.Enable = 'off';
            end
        end

        % Value changed function: h3
        function h3ValueChanged(app, event)
            
            h= str2double(app.h3.Value);
            k= str2double(app.k3.Value);
            l= str2double(app.l3.Value);
            a = str2double(app.a_bragg.Value);
            c = str2double(app.c_bragg.Value);
            if (~isnan(h) && ~isnan(k) && ~isnan(l) && ~isnan(a))
                % calculate and display d-spacing value
                if strcmpi(app.Blattice.Value,'Primitive Cubic') || strcmpi(app.Blattice.Value,'BCC') || strcmpi(app.Blattice.Value,'FCC') % Cubic Plane
                    dhkl = sqrt(a^2/(h^2+k^2+l^2));
                    if strcmpi(app.Blattice.Value,'Primitive Cubic') ||...
                            (strcmpi(app.Blattice.Value,'BCC') && mod(h+k+l,2)==0) || ... % h+k+l has to be even
                            (strcmpi(app.Blattice.Value,'FCC') && mod(h,2)==1 && mod(k,2)==1 && mod(l,2) == 1)  ||...
                            (strcmpi(app.Blattice.Value,'FCC') && mod(h,2)==0 && mod(k,2)==0 && mod(l,2) == 0) % hkl must be all odd or all even
                        if strcmpi(app.Blattice.Value,'FCC') && mod(h+k+l,4)==0
                            app.AllowedreflectionLabel.Text = 'Warning: potential forbidden reflection (h+k+l divisible by 4)';
                        else
                            app.AllowedreflectionLabel.Text = 'Allowed reflection';
                        end
                        app.AllowedreflectionLabel.FontColor = [0.47 0.67 0.19]; % dark green
                    else
                        app.AllowedreflectionLabel.Text = 'Warning: forbidden reflection';
                        app.AllowedreflectionLabel.FontColor = 'r';
                    end
                    app.d3.Value = num2str(dhkl,'%1.6f');
                elseif strcmpi(app.Blattice.Value,'Primitive Hexagonal')  % Hexagonal Plane
                    if ~isnan(c)
                        dhkl = sqrt(1/((4/3*((h^2+h*k+k^2)/a^2)) + l^2/c^2));
                        if mod(h+2*k,3)==0 && mod(l,2)==1 % forbidden when h+2k=3n and l is odd
                            app.AllowedreflectionLabel.Text = 'Warning: forbidden reflection';
                            app.AllowedreflectionLabel.FontColor = 'r';
                        else % all other cases
                            app.AllowedreflectionLabel.Text = 'Allowed reflection';
                            app.AllowedreflectionLabel.FontColor = [0.47 0.67 0.19]; % dark green
                        end
                        app.d3.Value = num2str(dhkl,'%1.6f');
                    else
                        app.AllowedreflectionLabel.Text = '...';
                        app.d3.Value = '';
                    end
                end
            else
                app.AllowedreflectionLabel.Text = '...';
                app.d3.Value = '';
            end
            
            CalculateBragg(app)
            
        end

        % Value changed function: k3
        function k3ValueChanged(app, event)
            h3ValueChanged(app, event)
        end

        % Value changed function: l3
        function l3ValueChanged(app, event)
            h3ValueChanged(app, event)
        end

        % Value changed function: a_bragg
        function a_braggValueChanged(app, event)
            h3ValueChanged(app, event)
        end

        % Value changed function: c_bragg
        function c_braggValueChanged(app, event)
            h3ValueChanged(app, event)
        end

        % Value changed function: Blattice
        function BlatticeValueChanged(app, event)
            h3ValueChanged(app, event) % update d
            SelectparametertocalculateButtonGroupSelectionChanged(app, event)
            CalculateBragg(app)
        end

        % Selection changed function: 
        % SelectparametertocalculateButtonGroup
        function SelectparametertocalculateButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectparametertocalculateButtonGroup.SelectedObject.Text;
            
            app.h3.Editable = 'on';
            app.k3.Editable = 'on';
            app.l3.Editable = 'on';
            app.a_bragg.Editable = 'on';
            app.h3.BackgroundColor = 'y';
            app.k3.BackgroundColor = 'y';
            app.l3.BackgroundColor = 'y';
            app.a_bragg.BackgroundColor = 'y';
            if strcmpi(app.Blattice.Value,'Primitive Hexagonal')
                app.c_bragg.BackgroundColor = 'y';
                app.c_bragg.Editable = 'on';
            else
                app.c_bragg.BackgroundColor = 'w';
                app.c_bragg.Editable = 'off';
                app.c_bragg.Value = '';
            end
            app.nEditField.BackgroundColor = 'y';
            app.theta_bragg.BackgroundColor = 'y';
            app.lambda_bragg.BackgroundColor = 'y';
            app.lambda_bragg.Editable = 'on';
            app.nEditField.Editable = 'on';
            app.theta_bragg.Editable = 'on';
            
            if contains(selectedButton,'wavelength') % need hkl, a, c, n, and theta
                app.lambda_bragg.Editable = 'off';
                app.lambda_bragg.BackgroundColor = 'w'; % variable to calculate
            elseif contains(selectedButton,'reflection')  % need hkl, a, c, wavelength, and theta
                app.nEditField.Editable = 'off';
                app.nEditField.BackgroundColor = 'w'; % variable to calculate
            elseif contains(selectedButton,'angle') % need hkl, a, c, wavelength, and n
                app.theta_bragg.Editable = 'off';
                app.theta_bragg.BackgroundColor = 'w'; % variable to calculate
            elseif contains(selectedButton,'spacing') % n, wavelength, and theta
                app.h3.BackgroundColor = 'w';
                app.k3.BackgroundColor = 'w';
                app.l3.BackgroundColor = 'w';
                app.a_bragg.BackgroundColor = 'w';
                app.c_bragg.BackgroundColor = 'w';
                app.h3.Value = '';
                app.k3.Value = '';
                app.l3.Value = '';
                app.a_bragg.Value = '';
                app.c_bragg.Value = '';
                app.h3.Editable = 'off';
                app.k3.Editable = 'off';
                app.l3.Editable = 'off';
                app.a_bragg.Editable = 'off';
                app.c_bragg.Editable = 'off';
                app.d3.BackgroundColor = 'w'; % variable to calculate
            end
            
            CalculateBragg(app) % calculate the variable
            
        end

        % Value changed function: lambda_bragg
        function lambda_braggValueChanged(app, event)

            CalculateBragg(app)
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            CalculateBragg(app)
        end

        % Value changed function: theta_bragg
        function theta_braggValueChanged(app, event)
            CalculateBragg(app)
        end

        % Button pushed function: ClearPlotsButton_2
        function ClearPlotsButton_2Pushed(app, event)
            cla(app.UIAxes_4)
            cla(app.UIAxes_5)
        end

        % Value changed function: AxisView_2
        function AxisView_2ValueChanged(app, event)
            value = app.AxisView_2.Value;
            
            if strcmpi(value,'X-Y plane')
                view(app.UIAxes_4,2); % X-Y plane
                view(app.UIAxes_5,2); % X-Y plane
            elseif strcmpi(value,'X-Z plane')
                view(app.UIAxes_4,[0 0]); % X-Z plane
                view(app.UIAxes_5,[0 0]); % X-Z plane
            elseif strcmpi(value,'Y-Z plane')
                view(app.UIAxes_4,[90 0]); % Y-Z plane
                view(app.UIAxes_5,[90 0]); % Y-Z plane
            else
                view(app.UIAxes_4,3); % 3-D view
                view(app.UIAxes_5,3); % 3-D view
            end
            
        end

        % Value changed function: ViewingDirectionDropDown
        function ViewingDirectionDropDownValueChanged(app, event)
            if strcmp(app.ViewingDirectionDropDown.Value,'a') % x-z
                app.AxisView_2.Value =  'X-Z plane';
            elseif strcmp(app.ViewingDirectionDropDown.Value,'b') % y-z
                app.AxisView_2.Value =  'Y-Z plane';
            elseif strcmp(app.ViewingDirectionDropDown.Value,'c') % x-y
                app.AxisView_2.Value =  'X-Y plane';
            end
            AxisView_2ValueChanged(app,event)
        end

        % Button pushed function: CellPlot_2
        function CellPlot_2ButtonPushed(app, event)
            ViewingDirectionDropDownValueChanged(app,event);
            app.ReciprocalLatticeFlag = 1;
            CellPlotPushed(app, event)
        end

        % Button pushed function: plot1_2
        function plot1_2ButtonPushed(app, event)
            app.ReciprocalLatticeFlag = 1;
            CellPlot_2ButtonPushed(app,event)
            h =  app.(['h',num2str(app.iPlane)]).Value;
            k = app.(['k',num2str(app.iPlane)]).Value;
            l = app.(['l',num2str(app.iPlane)]).Value;
            a = app.length1_2.Value;
            b = app.length2_2.Value;
            c = app.length3_2.Value;
            app.ReciprocalLatticeFlag = 1; % set to 1 again because CellPlot_2ButtonPushed sets it to 0
            PlanePoints = PlotPlane(app,h, k, l, a, b, c, app.points);
            
            % if d* exists, plot reciprocal lattice
            if ~strcmpi(app.(['dstar',num2str(app.iPlane)]).Value,'')
                if app.holdon_2.Value == 1
                    hold(app.UIAxes_5,'on'); % hold axes on after first 3-D line has been drawn
                end
                P1 = PlanePoints(:,1)';
                P2 = PlanePoints(:,2)';
                P3 = PlanePoints(:,3)';
                normal = cross(P1-P2,P1-P3); % calculate normal vector
                ReciprocalPoint = str2double(app.(['dstar',num2str(app.iPlane)]).Value)*normal; % magnitude times direction
                plot3(app.UIAxes_5,[0; ReciprocalPoint(1)], [0; ReciprocalPoint(2)], [0; ReciprocalPoint(3)])
            end
            
            ViewingDirectionDropDownValueChanged(app,event);
            app.iPlane = 4;
        end

        % Button pushed function: plot1_3
        function plot1_3ButtonPushed(app, event)
            app.iPlane = 5;
            plot1_2ButtonPushed(app,event);
        end

        % Button pushed function: plot1_4
        function plot1_4ButtonPushed(app, event)
           app.iPlane = 6;
            plot1_2ButtonPushed(app,event);
        end

        % Button pushed function: plot1_5
        function plot1_5ButtonPushed(app, event)
            app.iPlane = 7;
            plot1_2ButtonPushed(app,event);
        end

        % Value changed function: h4
        function h4ValueChanged(app, event)
            if app.hkl < 4
                app.hkl = 4;
            end
            h= app.(['h',num2str(app.hkl)]).Value;
            k= app.(['k',num2str(app.hkl)]).Value;
            l= app.(['l',num2str(app.hkl)]).Value;
            
            a = app.length1_2.Value;
            b = app.length2_2.Value;
            c = app.length3_2.Value;
            beta = app.angle2_2.Value;
            
            % calculate and display d-spacing value
            if a==b && b == c % Cubic Plane
                dhkl = sqrt(a^2/(h^2+k^2+l^2));
            elseif a == b && b~=c % Tetragonal Plane
                dhkl = sqrt(1/(((h^2+k^2)/a^2) + l^2/c^2));
            elseif a~=b && b~=c && c~=a % Orthorhombic Plane
                dhkl = sqrt(1/(h^2/a^2+k^2/b^2+l^2/c^2));
            end
            
            if isnumeric(app.wavelengthRL.Value)
                app.(['dstar',num2str(app.hkl)]).Value = num2str(app.wavelengthRL.Value/dhkl,'%1.6f');
            else
                app.(['dstar',num2str(app.hkl)]).Value = '-';
            end
            
            app.(['d',num2str(app.hkl)]).Value = num2str(dhkl,'%1.6f');
            app.hkl = 1;
        end

        % Value changed function: wavelengthRL
        function wavelengthRLValueChanged(app, event)
            % whenever wavelength value changes re-calculate all d* values
            for i = 4:7
                app.hkl = i;
                h4ValueChanged(app,event)
            end
        end

        % Value changed function: k4
        function k4ValueChanged(app, event)
            app.hkl = 4;
            h4ValueChanged(app,event)
        end

        % Value changed function: l4
        function l4ValueChanged(app, event)
            app.hkl = 4;
            h4ValueChanged(app,event)
        end

        % Value changed function: h5
        function h5ValueChanged(app, event)
            app.hkl = 5;
            h4ValueChanged(app,event)
        end

        % Value changed function: k5
        function k5ValueChanged(app, event)
            app.hkl = 5;
            h4ValueChanged(app,event)
        end

        % Value changed function: l5
        function l5ValueChanged(app, event)
            app.hkl = 5;
            h4ValueChanged(app,event)
        end

        % Value changed function: h6
        function h6ValueChanged(app, event)
            app.hkl = 6;
            h4ValueChanged(app,event)
        end

        % Value changed function: k6
        function k6ValueChanged(app, event)
            app.hkl = 6;
            h4ValueChanged(app,event)
        end

        % Value changed function: l6
        function l6ValueChanged(app, event)
            app.hkl = 6;
            h4ValueChanged(app,event)
        end

        % Value changed function: h7
        function h7ValueChanged(app, event)
            app.hkl = 7;
            h4ValueChanged(app,event)
        end

        % Value changed function: k7
        function k7ValueChanged(app, event)
            app.hkl = 7;
            h4ValueChanged(app,event)
        end

        % Value changed function: l7
        function l7ValueChanged(app, event)
            app.hkl = 7;
            h4ValueChanged(app,event)
        end

        % Value changed function: holdon_2
        function holdon_2ValueChanged(app, event)
            value = app.holdon_2.Value;
            
        end

        % Button pushed function: Plottet
        function PlottetButtonPushed(app, event)
            CellPlotPushed(app, event)
%             point = app.points;
            a = app.length1.Value;
            b = app.length2.Value;
            c = app.length3.Value;
            R = app.IRtet.Value;
            
            app.AxisView.Value = '3-D view';
            view(app.UIAxes,3); % 3-D view
            
            % tetrahedral interstitial sites
            point = [b*3/4, a*3/4, c*3/4;...
            b*1/4, a*3/4, c*1/4;...
            b*1/4, a*3/4, c*3/4;...
            b*3/4, a*1/4, c*1/4;...
            b*1/4, a*1/4, c*3/4;...
            b*3/4, a*1/4, c*3/4;...
            b*1/4, a*1/4, c*1/4;...
            b*3/4, a*3/4, c*1/4]; % x, y, z
            
            figure1 = figure;
            % plot a sphere with the centerpoint at each of the points in "app.points"
            [x y z] = sphere;
            for i = 1:length(point)
                xshift = point(i,1); % center of the sphere is point{i}
                yshift = point(i,2); % center of the sphere is point{i}
                zshift = point(i,3); % center of the sphere is point{i}
                
                hSurface = surf(app.UIAxes,R*x+xshift, R*y+yshift, R*z+zshift);
                set(hSurface,'FaceColor',[1 0 0], ...
                    'FaceAlpha',0.3,'FaceLighting','gouraud','EdgeColor','none');
            end
            
            close(figure1)
            
        end

        % Button pushed function: Plotoct
        function PlotoctButtonPushed(app, event)
            CellPlotPushed(app, event)
%             point = app.points;
            a = app.length1.Value;
            b = app.length2.Value;
            c = app.length3.Value;
            R = app.IRoct.Value;
            
            app.AxisView.Value = '3-D view';
            view(app.UIAxes,3); % 3-D view
            
            % tetrahedral interstitial sites
            point = [b*1/2, a*0, c*0;...
            b*0, a*0, c*1/2;...
            b*0, a*1/2, c*0;...
            b*1/2, a*1/2, c*1/2]; % x, y, z
            
            figure1 = figure;
            % plot a sphere with the centerpoint at each of the points in "app.points"
            [x y z] = sphere;
            for i = 1:length(point)
                xshift = point(i,1); % center of the sphere is point{i}
                yshift = point(i,2); % center of the sphere is point{i}
                zshift = point(i,3); % center of the sphere is point{i}
                
                hSurface = surf(app.UIAxes,R*x+xshift, R*y+yshift, R*z+zshift);
                set(hSurface,'FaceColor',[0 1 0], ...
                    'FaceAlpha',0.3,'FaceLighting','gouraud','EdgeColor','none');
            end
            
            close(figure1)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CrystalStructureTab = uitab(app.TabGroup);
            app.CrystalStructureTab.Title = 'Crystal Structure';
            app.UIAxes = uiaxes(app.CrystalStructureTab);
            title(app.UIAxes, 'Crystal Structure')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Step1UnitCellGeometryPanel = uipanel(app.CrystalStructureTab);
            app.Step1UnitCellGeometryPanel.Title = 'Step 1) Unit Cell Geometry';
            app.AxialLengthsnmLabel = uilabel(app.Step1UnitCellGeometryPanel);
            app.AxialLengthsnmLabel.Text = 'Axial Lengths (nm)';
            app.AnglesLabel = uilabel(app.Step1UnitCellGeometryPanel);
            app.AnglesLabel.Text = 'Angles (°)';
            app.Label = uilabel(app.Step1UnitCellGeometryPanel);
            app.Label.Text = 'α';
            app.angle1 = uieditfield(app.Step1UnitCellGeometryPanel, 'numeric');
            app.angle1.ValueChangedFcn = createCallbackFcn(app, @angle1ValueChanged, true);
            app.angle1.Value = 90;
            app.EditField_2Label = uilabel(app.Step1UnitCellGeometryPanel);
            app.EditField_2Label.Text = 'ß';
            app.angle2 = uieditfield(app.Step1UnitCellGeometryPanel, 'numeric');
            app.angle2.ValueChangedFcn = createCallbackFcn(app, @angle2ValueChanged, true);
            app.angle2.Value = 90;
            app.Label_2 = uilabel(app.Step1UnitCellGeometryPanel);
            app.Label_2.Text = 'γ';
            app.angle3 = uieditfield(app.Step1UnitCellGeometryPanel, 'numeric');
            app.angle3.ValueChangedFcn = createCallbackFcn(app, @angle3ValueChanged, true);
            app.angle3.Value = 90;
            app.CellPlot = uibutton(app.Step1UnitCellGeometryPanel, 'push');
            app.CellPlot.ButtonPushedFcn = createCallbackFcn(app, @CellPlotPushed, true);
            app.CellPlot.Text = 'Plot';
            app.SystemLabel = uilabel(app.Step1UnitCellGeometryPanel);
            app.SystemLabel.Text = 'System:';
            app.SystemType = uieditfield(app.Step1UnitCellGeometryPanel, 'text');
            app.SystemType.Enable = 'off';
            app.SystemType.Value = 'Cubic';
            app.aEditFieldLabel = uilabel(app.Step1UnitCellGeometryPanel);
            app.aEditFieldLabel.Text = 'a';
            app.length1 = uieditfield(app.Step1UnitCellGeometryPanel, 'numeric');
            app.length1.ValueChangedFcn = createCallbackFcn(app, @length1ValueChanged, true);
            app.length1.Value = 1;
            app.cEditFieldLabel = uilabel(app.Step1UnitCellGeometryPanel);
            app.cEditFieldLabel.Text = 'c';
            app.length3 = uieditfield(app.Step1UnitCellGeometryPanel, 'numeric');
            app.length3.ValueChangedFcn = createCallbackFcn(app, @length3ValueChanged, true);
            app.length3.Value = 1;
            app.bEditFieldLabel = uilabel(app.Step1UnitCellGeometryPanel);
            app.bEditFieldLabel.Text = 'b';
            app.length2 = uieditfield(app.Step1UnitCellGeometryPanel, 'numeric');
            app.length2.ValueChangedFcn = createCallbackFcn(app, @length2ValueChanged, true);
            app.length2.Value = 1;
            app.Step2BravaisLatticePanel = uipanel(app.CrystalStructureTab);
            app.Step2BravaisLatticePanel.Title = 'Step 2) Bravais Lattice';
            app.BravaisLatticeDropDownLabel = uilabel(app.Step2BravaisLatticePanel);
            app.BravaisLatticeDropDownLabel.Text = 'Bravais Lattice';
            app.BravaisLattice = uidropdown(app.Step2BravaisLatticePanel);
            app.BravaisLattice.Items = {'Simple', 'Body-centered', 'Face-centered'};
            app.BravaisLattice.ValueChangedFcn = createCallbackFcn(app, @BravaisLatticeValueChanged, true);
            app.BravaisLattice.Value = 'Simple';
            app.AtomicRadiusnmEditFieldLabel = uilabel(app.Step2BravaisLatticePanel);
            app.AtomicRadiusnmEditFieldLabel.Text = 'Atomic Radius (nm)';
            app.AtomicRadius = uieditfield(app.Step2BravaisLatticePanel, 'numeric');
            app.AtomicRadius.ValueChangedFcn = createCallbackFcn(app, @AtomicRadiusValueChanged, true);
            app.AtomicRadius.Value = 0.3;
            app.PlotAtom = uibutton(app.Step2BravaisLatticePanel, 'push');
            app.PlotAtom.ButtonPushedFcn = createCallbackFcn(app, @PlotAtomButtonPushed, true);
            app.PlotAtom.Text = 'Plot';
            app.Step3InterstitialsPanel = uipanel(app.CrystalStructureTab);
            app.Step3InterstitialsPanel.Title = 'Step 3) Interstitials';
            app.InterstitialRadiusnmEditFieldLabel = uilabel(app.Step3InterstitialsPanel);
            app.InterstitialRadiusnmEditFieldLabel.Text = 'Interstitial Radius (nm)';
            app.IRtet = uieditfield(app.Step3InterstitialsPanel, 'numeric');
            app.IRtet.Value = 0.13;
            app.Plottet = uibutton(app.Step3InterstitialsPanel, 'push');
            app.Plottet.ButtonPushedFcn = createCallbackFcn(app, @PlottetButtonPushed, true);
            app.Plottet.Text = 'Plot';
            app.OctahedralSiteLabel = uilabel(app.Step3InterstitialsPanel);
            app.OctahedralSiteLabel.Text = 'Octahedral Site';
            app.InterstitialRadiusnmEditField_2Label = uilabel(app.Step3InterstitialsPanel);
            app.InterstitialRadiusnmEditField_2Label.Text = 'Interstitial Radius (nm)';
            app.IRoct = uieditfield(app.Step3InterstitialsPanel, 'numeric');
            app.IRoct.Value = 0.2;
            app.Plotoct = uibutton(app.Step3InterstitialsPanel, 'push');
            app.Plotoct.ButtonPushedFcn = createCallbackFcn(app, @PlotoctButtonPushed, true);
            app.Plotoct.Text = 'Plot';
            app.TetrahedralSiteLabel = uilabel(app.Step3InterstitialsPanel);
            app.TetrahedralSiteLabel.Text = 'Tetrahedral Site';
            app.ClearPlotButton = uibutton(app.CrystalStructureTab, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.holdon = uicheckbox(app.CrystalStructureTab);
            app.holdon.Text = 'hold on';
            app.ViewpointLabel = uilabel(app.CrystalStructureTab);
            app.ViewpointLabel.Text = 'Viewpoint';
            app.AxisView = uidropdown(app.CrystalStructureTab);
            app.AxisView.Items = {'X-Y plane', 'X-Z plane', 'Y-Z plane', '3-D view'};
            app.AxisView.ValueChangedFcn = createCallbackFcn(app, @AxisViewValueChanged, true);
            app.AxisView.Value = '3-D view';
            app.PlanesandDirectionsPanel = uipanel(app.CrystalStructureTab);
            app.PlanesandDirectionsPanel.Title = 'Planes and Directions';
            app.hLabel = uilabel(app.PlanesandDirectionsPanel);
            app.hLabel.Text = 'h';
            app.h1 = uieditfield(app.PlanesandDirectionsPanel, 'numeric');
            app.h1.ValueChangedFcn = createCallbackFcn(app, @h1ValueChanged, true);
            app.h1.Value = 1;
            app.lLabel = uilabel(app.PlanesandDirectionsPanel);
            app.lLabel.Text = 'l';
            app.l1 = uieditfield(app.PlanesandDirectionsPanel, 'numeric');
            app.l1.ValueChangedFcn = createCallbackFcn(app, @l1ValueChanged, true);
            app.l1.Value = 1;
            app.kLabel = uilabel(app.PlanesandDirectionsPanel);
            app.kLabel.Text = 'k';
            app.k1 = uieditfield(app.PlanesandDirectionsPanel, 'numeric');
            app.k1.ValueChangedFcn = createCallbackFcn(app, @k1ValueChanged, true);
            app.k1.Value = 1;
            app.family1 = uicheckbox(app.PlanesandDirectionsPanel);
            app.family1.Text = 'Family';
            app.plot1 = uibutton(app.PlanesandDirectionsPanel, 'push');
            app.plot1.ButtonPushedFcn = createCallbackFcn(app, @plot1ButtonPushed, true);
            app.plot1.Text = 'Plot';
            app.h2 = uieditfield(app.PlanesandDirectionsPanel, 'numeric');
            app.h2.ValueChangedFcn = createCallbackFcn(app, @h2ValueChanged, true);
            app.h2.Value = 1;
            app.l2 = uieditfield(app.PlanesandDirectionsPanel, 'numeric');
            app.l2.ValueChangedFcn = createCallbackFcn(app, @l2ValueChanged, true);
            app.l2.Value = 1;
            app.k2 = uieditfield(app.PlanesandDirectionsPanel, 'numeric');
            app.k2.ValueChangedFcn = createCallbackFcn(app, @k2ValueChanged, true);
            app.k2.Value = 1;
            app.PorD1 = uidropdown(app.PlanesandDirectionsPanel);
            app.PorD1.Items = {'Plane', 'Direction'};
            app.PorD1.ValueChangedFcn = createCallbackFcn(app, @PorD1ValueChanged, true);
            app.PorD1.Value = 'Plane';
            app.plot2 = uibutton(app.PlanesandDirectionsPanel, 'push');
            app.plot2.ButtonPushedFcn = createCallbackFcn(app, @plot2ButtonPushed, true);
            app.plot2.Text = 'Plot';
            app.family2 = uicheckbox(app.PlanesandDirectionsPanel);
            app.family2.Text = 'Family';
            app.PorD2 = uidropdown(app.PlanesandDirectionsPanel);
            app.PorD2.Items = {'Plane', 'Direction'};
            app.PorD2.ValueChangedFcn = createCallbackFcn(app, @PorD2ValueChanged, true);
            app.PorD2.Value = 'Direction';
            app.AnglebetweenspecifiedplanesdirectionsEditFieldLabel = uilabel(app.PlanesandDirectionsPanel);
            app.AnglebetweenspecifiedplanesdirectionsEditFieldLabel.Text = 'Angle between specified planes/directions (°):';
            app.pdangle = uieditfield(app.PlanesandDirectionsPanel, 'text');
            app.pdangle.Enable = 'off';
            app.pdangle.Value = '90';
            app.d2 = uieditfield(app.PlanesandDirectionsPanel, 'text');
            app.d2.Editable = 'off';
            app.d2.Value = '-';
            app.dspacingnmEditFieldLabel = uilabel(app.PlanesandDirectionsPanel);
            app.dspacingnmEditFieldLabel.Text = 'd-spacing (nm)';
            app.d1 = uieditfield(app.PlanesandDirectionsPanel, 'text');
            app.d1.Editable = 'off';
            app.d1.Value = '0.57735';
            app.ReciprocalLatticeTab = uitab(app.TabGroup);
            app.ReciprocalLatticeTab.Title = 'Reciprocal Lattice';
            app.UIAxes_4 = uiaxes(app.ReciprocalLatticeTab);
            title(app.UIAxes_4, 'Crystal Structure')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.ClearPlotsButton_2 = uibutton(app.ReciprocalLatticeTab, 'push');
            app.ClearPlotsButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotsButton_2Pushed, true);
            app.ClearPlotsButton_2.Text = 'Clear Plots';
            app.holdon_2 = uicheckbox(app.ReciprocalLatticeTab);
            app.holdon_2.ValueChangedFcn = createCallbackFcn(app, @holdon_2ValueChanged, true);
            app.holdon_2.Text = 'hold on';
            app.AxisView_2 = uidropdown(app.ReciprocalLatticeTab);
            app.AxisView_2.Items = {'X-Y plane', 'X-Z plane', 'Y-Z plane', '3-D view'};
            app.AxisView_2.ValueChangedFcn = createCallbackFcn(app, @AxisView_2ValueChanged, true);
            app.AxisView_2.Value = 'X-Y plane';
            app.PlanesPanel = uipanel(app.ReciprocalLatticeTab);
            app.PlanesPanel.Title = 'Planes';
            app.plot1_2 = uibutton(app.PlanesPanel, 'push');
            app.plot1_2.ButtonPushedFcn = createCallbackFcn(app, @plot1_2ButtonPushed, true);
            app.plot1_2.Text = 'Plot';
            app.plot1_3 = uibutton(app.PlanesPanel, 'push');
            app.plot1_3.ButtonPushedFcn = createCallbackFcn(app, @plot1_3ButtonPushed, true);
            app.plot1_3.Text = 'Plot';
            app.h5 = uieditfield(app.PlanesPanel, 'numeric');
            app.h5.ValueChangedFcn = createCallbackFcn(app, @h5ValueChanged, true);
            app.h5.Value = 2;
            app.l5 = uieditfield(app.PlanesPanel, 'numeric');
            app.l5.ValueChangedFcn = createCallbackFcn(app, @l5ValueChanged, true);
            app.k5 = uieditfield(app.PlanesPanel, 'numeric');
            app.k5.ValueChangedFcn = createCallbackFcn(app, @k5ValueChanged, true);
            app.d5 = uieditfield(app.PlanesPanel, 'text');
            app.d5.Editable = 'off';
            app.d5.Value = '0.5';
            app.plot1_4 = uibutton(app.PlanesPanel, 'push');
            app.plot1_4.ButtonPushedFcn = createCallbackFcn(app, @plot1_4ButtonPushed, true);
            app.plot1_4.Text = 'Plot';
            app.h6 = uieditfield(app.PlanesPanel, 'numeric');
            app.h6.ValueChangedFcn = createCallbackFcn(app, @h6ValueChanged, true);
            app.h6.Value = 1;
            app.l6 = uieditfield(app.PlanesPanel, 'numeric');
            app.l6.ValueChangedFcn = createCallbackFcn(app, @l6ValueChanged, true);
            app.k6 = uieditfield(app.PlanesPanel, 'numeric');
            app.k6.ValueChangedFcn = createCallbackFcn(app, @k6ValueChanged, true);
            app.k6.Value = 1;
            app.d6 = uieditfield(app.PlanesPanel, 'text');
            app.d6.Editable = 'off';
            app.d6.Value = '0.707107';
            app.plot1_5 = uibutton(app.PlanesPanel, 'push');
            app.plot1_5.ButtonPushedFcn = createCallbackFcn(app, @plot1_5ButtonPushed, true);
            app.plot1_5.Text = 'Plot';
            app.h7 = uieditfield(app.PlanesPanel, 'numeric');
            app.h7.ValueChangedFcn = createCallbackFcn(app, @h7ValueChanged, true);
            app.h7.Value = 3;
            app.l7 = uieditfield(app.PlanesPanel, 'numeric');
            app.l7.ValueChangedFcn = createCallbackFcn(app, @l7ValueChanged, true);
            app.k7 = uieditfield(app.PlanesPanel, 'numeric');
            app.k7.ValueChangedFcn = createCallbackFcn(app, @k7ValueChanged, true);
            app.k7.Value = 1;
            app.d7 = uieditfield(app.PlanesPanel, 'text');
            app.d7.Editable = 'off';
            app.d7.Value = '0.316228';
            app.Plotupto4planesatatimeLabel = uilabel(app.PlanesPanel);
            app.Plotupto4planesatatimeLabel.Text = 'Plot up to 4 planes at a time';
            app.dstar5 = uieditfield(app.PlanesPanel, 'text');
            app.dstar5.Editable = 'off';
            app.dstar5.Value = '0.308400';
            app.dstar6 = uieditfield(app.PlanesPanel, 'text');
            app.dstar6.Editable = 'off';
            app.dstar6.Value = '0.218072';
            app.dstar7 = uieditfield(app.PlanesPanel, 'text');
            app.dstar7.Editable = 'off';
            app.dstar7.Value = '0.487623';
            app.dspacingnmEditFieldLabel_2 = uilabel(app.PlanesPanel);
            app.dspacingnmEditFieldLabel_2.Text = 'd-spacing (nm)';
            app.d4 = uieditfield(app.PlanesPanel, 'text');
            app.d4.Editable = 'off';
            app.d4.Value = '0.316228';
            app.dLabel = uilabel(app.PlanesPanel);
            app.dLabel.Text = 'd*';
            app.dstar4 = uieditfield(app.PlanesPanel, 'text');
            app.dstar4.Editable = 'off';
            app.dstar4.Value = '0.487623';
            app.WavelengthnmEditFieldLabel = uilabel(app.PlanesPanel);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.wavelengthRL = uieditfield(app.PlanesPanel, 'numeric');
            app.wavelengthRL.ValueChangedFcn = createCallbackFcn(app, @wavelengthRLValueChanged, true);
            app.wavelengthRL.Value = 0.1542;
            app.hLabel_3 = uilabel(app.PlanesPanel);
            app.hLabel_3.Text = 'h';
            app.h4 = uieditfield(app.PlanesPanel, 'numeric');
            app.h4.ValueChangedFcn = createCallbackFcn(app, @h4ValueChanged, true);
            app.h4.Value = 1;
            app.lLabel_3 = uilabel(app.PlanesPanel);
            app.lLabel_3.Text = 'l';
            app.l4 = uieditfield(app.PlanesPanel, 'numeric');
            app.l4.ValueChangedFcn = createCallbackFcn(app, @l4ValueChanged, true);
            app.kLabel_3 = uilabel(app.PlanesPanel);
            app.kLabel_3.Text = 'k';
            app.k4 = uieditfield(app.PlanesPanel, 'numeric');
            app.k4.ValueChangedFcn = createCallbackFcn(app, @k4ValueChanged, true);
            app.k4.Value = 3;
            app.ViewpointLabel_2 = uilabel(app.ReciprocalLatticeTab);
            app.ViewpointLabel_2.Text = 'Viewpoint';
            app.UIAxes_5 = uiaxes(app.ReciprocalLatticeTab);
            title(app.UIAxes_5, 'Diffraction Pattern')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            app.UnitCellGeometryPanel = uipanel(app.ReciprocalLatticeTab);
            app.UnitCellGeometryPanel.Title = 'Unit Cell Geometry';
            app.AxialLengthsnmLabel_2 = uilabel(app.UnitCellGeometryPanel);
            app.AxialLengthsnmLabel_2.Text = 'Axial Lengths (nm)';
            app.AnglesLabel_2 = uilabel(app.UnitCellGeometryPanel);
            app.AnglesLabel_2.Text = 'Angles (°)';
            app.CellPlot_2 = uibutton(app.UnitCellGeometryPanel, 'push');
            app.CellPlot_2.ButtonPushedFcn = createCallbackFcn(app, @CellPlot_2ButtonPushed, true);
            app.CellPlot_2.Text = 'Plot';
            app.aEditFieldLabel_2 = uilabel(app.UnitCellGeometryPanel);
            app.aEditFieldLabel_2.Text = 'a';
            app.length1_2 = uieditfield(app.UnitCellGeometryPanel, 'numeric');
            app.length1_2.Value = 1;
            app.cEditFieldLabel_2 = uilabel(app.UnitCellGeometryPanel);
            app.cEditFieldLabel_2.Text = 'c';
            app.length3_2 = uieditfield(app.UnitCellGeometryPanel, 'numeric');
            app.length3_2.Value = 1;
            app.bEditFieldLabel_2 = uilabel(app.UnitCellGeometryPanel);
            app.bEditFieldLabel_2.Text = 'b';
            app.length2_2 = uieditfield(app.UnitCellGeometryPanel, 'numeric');
            app.length2_2.Value = 1;
            app.Label_7 = uilabel(app.UnitCellGeometryPanel);
            app.Label_7.Enable = 'off';
            app.Label_7.Text = 'α';
            app.angle1_2 = uieditfield(app.UnitCellGeometryPanel, 'numeric');
            app.angle1_2.Enable = 'off';
            app.angle1_2.Value = 90;
            app.EditField_2Label_2 = uilabel(app.UnitCellGeometryPanel);
            app.EditField_2Label_2.Enable = 'off';
            app.EditField_2Label_2.Text = 'ß';
            app.angle2_2 = uieditfield(app.UnitCellGeometryPanel, 'numeric');
            app.angle2_2.Enable = 'off';
            app.angle2_2.Value = 90;
            app.Label_8 = uilabel(app.UnitCellGeometryPanel);
            app.Label_8.Enable = 'off';
            app.Label_8.Text = 'γ';
            app.angle3_2 = uieditfield(app.UnitCellGeometryPanel, 'numeric');
            app.angle3_2.Enable = 'off';
            app.angle3_2.Value = 90;
            app.ViewingDirectionDropDownLabel = uilabel(app.UnitCellGeometryPanel);
            app.ViewingDirectionDropDownLabel.Text = 'Viewing Direction';
            app.ViewingDirectionDropDown = uidropdown(app.UnitCellGeometryPanel);
            app.ViewingDirectionDropDown.Items = {'a', 'b', 'c'};
            app.ViewingDirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewingDirectionDropDownValueChanged, true);
            app.ViewingDirectionDropDown.Value = 'c';
            app.XRayCrystallographyTab = uitab(app.TabGroup);
            app.XRayCrystallographyTab.Title = 'X-Ray Crystallography';
            app.XRayCrystallographyBraggsLawPanel = uipanel(app.XRayCrystallographyTab);
            app.XRayCrystallographyBraggsLawPanel.Title = 'X-Ray Crystallography: Bragg''s Law';
            app.SpecifyBravaisLatticeDropDownLabel = uilabel(app.XRayCrystallographyBraggsLawPanel);
            app.SpecifyBravaisLatticeDropDownLabel.Text = 'Specify Bravais Lattice';
            app.Blattice = uidropdown(app.XRayCrystallographyBraggsLawPanel);
            app.Blattice.Items = {'Primitive Cubic', 'BCC', 'FCC', 'Primitive Hexagonal'};
            app.Blattice.ValueChangedFcn = createCallbackFcn(app, @BlatticeValueChanged, true);
            app.Blattice.Value = 'Primitive Cubic';
            app.SelectparametertocalculateButtonGroup = uibuttongroup(app.XRayCrystallographyBraggsLawPanel);
            app.SelectparametertocalculateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectparametertocalculateButtonGroupSelectionChanged, true);
            app.SelectparametertocalculateButtonGroup.Title = 'Select parameter to calculate';
            app.xraywavelengthButton = uiradiobutton(app.SelectparametertocalculateButtonGroup);
            app.xraywavelengthButton.Text = 'λ: x-ray wavelength';
            app.xraywavelengthButton.Value = true;
            app.nOrderofreflectionButton = uiradiobutton(app.SelectparametertocalculateButtonGroup);
            app.nOrderofreflectionButton.Text = 'n: Order of reflection';
            app.BraggangleButton = uiradiobutton(app.SelectparametertocalculateButtonGroup);
            app.BraggangleButton.Text = 'θ: Bragg angle';
            app.dPlanespacingButton = uiradiobutton(app.SelectparametertocalculateButtonGroup);
            app.dPlanespacingButton.Text = 'd: Plane spacing';
            app.dnmEditFieldLabel = uilabel(app.XRayCrystallographyBraggsLawPanel);
            app.dnmEditFieldLabel.Text = 'd (nm)';
            app.d3 = uieditfield(app.XRayCrystallographyBraggsLawPanel, 'text');
            app.d3.Editable = 'off';
            app.d3.Value = '0.224011';
            app.nmEditFieldLabel = uilabel(app.XRayCrystallographyBraggsLawPanel);
            app.nmEditFieldLabel.Text = 'λ (nm)';
            app.lambda_bragg = uieditfield(app.XRayCrystallographyBraggsLawPanel, 'text');
            app.lambda_bragg.ValueChangedFcn = createCallbackFcn(app, @lambda_braggValueChanged, true);
            app.lambda_bragg.Editable = 'off';
            app.lambda_bragg.Value = '0.15397';
            app.nEditFieldLabel = uilabel(app.XRayCrystallographyBraggsLawPanel);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.XRayCrystallographyBraggsLawPanel, 'text');
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.nEditField.Value = '1';
            app.Label_6 = uilabel(app.XRayCrystallographyBraggsLawPanel);
            app.Label_6.Text = 'θ (°)';
            app.theta_bragg = uieditfield(app.XRayCrystallographyBraggsLawPanel, 'text');
            app.theta_bragg.ValueChangedFcn = createCallbackFcn(app, @theta_braggValueChanged, true);
            app.theta_bragg.Value = '20.1';
            app.hEditFieldLabel = uilabel(app.XRayCrystallographyBraggsLawPanel);
            app.hEditFieldLabel.Text = 'h';
            app.h3 = uieditfield(app.XRayCrystallographyBraggsLawPanel, 'text');
            app.h3.ValueChangedFcn = createCallbackFcn(app, @h3ValueChanged, true);
            app.h3.Value = '1';
            app.kEditFieldLabel = uilabel(app.XRayCrystallographyBraggsLawPanel);
            app.kEditFieldLabel.Text = 'k';
            app.k3 = uieditfield(app.XRayCrystallographyBraggsLawPanel, 'text');
            app.k3.ValueChangedFcn = createCallbackFcn(app, @k3ValueChanged, true);
            app.k3.Value = '1';
            app.lEditFieldLabel = uilabel(app.XRayCrystallographyBraggsLawPanel);
            app.lEditFieldLabel.Text = 'l';
            app.l3 = uieditfield(app.XRayCrystallographyBraggsLawPanel, 'text');
            app.l3.ValueChangedFcn = createCallbackFcn(app, @l3ValueChanged, true);
            app.l3.Value = '0';
            app.EnterallhighlightedvaluesLabel = uilabel(app.XRayCrystallographyBraggsLawPanel);
            app.EnterallhighlightedvaluesLabel.Text = 'Enter all highlighted values';
            app.AllowedreflectionLabel = uilabel(app.XRayCrystallographyBraggsLawPanel);
            app.AllowedreflectionLabel.Text = 'Allowed reflection';
            app.anmLabel = uilabel(app.XRayCrystallographyBraggsLawPanel);
            app.anmLabel.Text = 'a (nm)';
            app.a_bragg = uieditfield(app.XRayCrystallographyBraggsLawPanel, 'text');
            app.a_bragg.ValueChangedFcn = createCallbackFcn(app, @a_braggValueChanged, true);
            app.a_bragg.Value = '0.3168';
            app.cnmLabel = uilabel(app.XRayCrystallographyBraggsLawPanel);
            app.cnmLabel.Text = 'c (nm)';
            app.c_bragg = uieditfield(app.XRayCrystallographyBraggsLawPanel, 'text');
            app.c_bragg.ValueChangedFcn = createCallbackFcn(app, @c_braggValueChanged, true);
            app.c_bragg.Editable = 'off';
            app.PoleFiguresTab = uitab(app.TabGroup);
            app.PoleFiguresTab.Title = 'Pole Figures';
            app.PlotPoles = uibutton(app.PoleFiguresTab, 'push');
            app.PlotPoles.ButtonPushedFcn = createCallbackFcn(app, @PlotPolesButtonPushed, true);
            app.PlotPoles.Text = 'Plot Pole Figures';
            app.UIAxes_2 = uiaxes(app.PoleFiguresTab);
            title(app.UIAxes_2, 'Pole Figure')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.ClearPlotsButton = uibutton(app.PoleFiguresTab, 'push');
            app.ClearPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotsButtonPushed, true);
            app.ClearPlotsButton.Text = 'Clear Plots';
            app.holdon_pole = uicheckbox(app.PoleFiguresTab);
            app.holdon_pole.Text = 'hold on';
            app.UIAxes_3 = uiaxes(app.PoleFiguresTab);
            title(app.UIAxes_3, 'Pole Figure')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.Panel = uipanel(app.PoleFiguresTab);
            app.FamilyofdirectionsLabel = uilabel(app.Panel);
            app.FamilyofdirectionsLabel.Text = 'Family of directions';
            app.hLabel_2 = uilabel(app.Panel);
            app.hLabel_2.Text = 'h';
            app.h_pole = uieditfield(app.Panel, 'numeric');
            app.h_pole.Value = 1;
            app.lLabel_2 = uilabel(app.Panel);
            app.lLabel_2.Text = 'l';
            app.l_pole = uieditfield(app.Panel, 'numeric');
            app.l_pole.Value = 1;
            app.kLabel_2 = uilabel(app.Panel);
            app.kLabel_2.Text = 'k';
            app.k_pole = uieditfield(app.Panel, 'numeric');
            app.k_pole.Value = 1;
            app.Panel_2 = uipanel(app.PoleFiguresTab);
            app.EulerAnglesLabel = uilabel(app.Panel_2);
            app.EulerAnglesLabel.Text = 'Euler Angles (°)';
            app.EulerFile = uieditfield(app.Panel_2, 'text');
            app.EulerFile.Enable = 'off';
            app.EulerFile.Value = 'texran.ang';
            app.usefile = uicheckbox(app.Panel_2);
            app.usefile.ValueChangedFcn = createCallbackFcn(app, @usefileValueChanged, true);
            app.usefile.Text = 'Use File';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = 'ψ';
            app.psi = uieditfield(app.Panel_2, 'numeric');
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = 'ϕ';
            app.phi = uieditfield(app.Panel_2, 'numeric');
            app.phi.Value = 45;
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = 'θ';
            app.theta = uieditfield(app.Panel_2, 'numeric');
            app.theta.Value = 35.26;
        end
    end

    methods (Access = public)

        % Construct app
        function app = FinalProject

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end